classdef Confirmation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        LoadButton  matlab.ui.control.Button
        SaveButton  matlab.ui.control.Button
        KeeporModifytheentriesThenclosethewindowLabel  matlab.ui.control.Label
        HTML        matlab.ui.control.HTML
        UITable     matlab.ui.control.Table
    end

    % This class will display the parameters that have been detected and related
    % information, and will ask the user if some modifications are
    % necessary for the later plot.
    properties (Access = private)
        Tag % To classify the different instances of "Confirmation"
    end
    
    methods (Access = public)
        
        function defineTag(app, tag)
            app.Tag = tag;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, variableInfo, parametersInfo, parameters, common, missing, additional)
            disp("Parameter Manager opening... Please do as instructed.");
            app.Tag = "Default";
            app.UITable.ColumnName = {"Name", "Display_Name", "Unit_Prefix", "Unit", "Operation"};
            app.UITable.ColumnEditable = true;

            % Add table rows for the main variable; 
            app.UITable.RowName{1} = 'variable';

            % Fill in values to the table
            tableRow = table(variableInfo(1), {[variableInfo{2} ' ' variableInfo{3}]}, ...
                categorical({'-'},{'q','r','y','z','a','f','p','n','$\mu$','m','c','d','-','da','h','k','M','G','T','P','E','Z','Y','R','Q'}), ...
                variableInfo(4), {'all'});
            app.UITable.Data = tableRow;
            warning('on')

            % Add table rows for the parameters of interest
            for c = 1:length(common)
                m = find(matches(parameters,common{c}));
                switch m
                    case 1
                        app.UITable.RowName{c+1} = 'x axis';
                    case 2
                        app.UITable.RowName{c+1} = 'colours';
                    case 3
                        app.UITable.RowName{c+1} = 'subfigures';
                    case 4
                        app.UITable.RowName{c+1} = 'figures';
                    case 5
                        app.UITable.RowName{c+1} = 'line types';
                    case 6
                        app.UITable.RowName{c+1} = 'marker types';
                end
                tableRow = table(common(c), {parametersInfo.(common{c})(1).name}, ...
                    categorical({'-'},{'q','r','y','z','a','f','p','n','$\mu$','m','c','d','-','da','h','k','M','G','T','P','E','Z','Y','R','Q'}), ...
                    {parametersInfo.(common{c})(1).other},{'all'});
                 app.UITable.Data(c+1,:) = tableRow;
            end

            if(isempty(c))
                c = 0;
            end

            % Add table rows for the parameters that are not of interest
            for ad = c+1:(c+length(additional))
                app.UITable.RowName{ad+1} = ['Working Point ' num2str(ad-c)];
                tableRow = table(additional(ad-c), {parametersInfo.(additional{ad-c})(1).name}, ...
                    categorical({'-'},{'q','r','y','z','a','f','p','n','$\mu$','m','c','d','-','da','h','k','M','G','T','P','E','Z','Y','R','Q'}), ...
                    {parametersInfo.(additional{ad-c})(1).other},{'ignore'});
                 app.UITable.Data(ad+1,:) = tableRow;
            end

            if(isempty(ad))
                ad = c;
            end
            
            % Remove the 'none'
            missing = setdiff(missing, {'none'});

            % Add table rows for parameters of interest that are missing
            ms = 0;
            for ms = ad+1:(ad+length(missing))
                app.UITable.RowName{ms+1} = ['Missing ' num2str(ms-ad)];
                tableRow = table(missing(ms-ad), {'?'}, ...
                    categorical({'-'},{'q','r','y','z','a','f','p','n','$\mu$','m','c','d','-','da','h','k','M','G','T','P','E','Z','Y','R','Q'}), ...
                    {'?'},{'error'});
                app.UITable.Data(ms+1,:) = tableRow;
                addStyle(app.UITable, uistyle('BackgroundColor','red'),'row',ms+1);
            end
            
            % Define the name of the columns and rows.
            app.UITable.Data.Properties.VariableNames = app.UITable.ColumnName;
            app.UITable.Data.Properties.RowNames = app.UITable.RowName;

            if(length(missing))
                error('Some required parameters were not found in the files. Please check if anything is missing.');
            end

        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            SavedRowNames = app.UITable.RowName;
            SavedTableData = app.UITable.Data;
            save(join(["ParameterManager",app.Tag,".mat"],''), 'SavedRowNames', 'SavedTableData');
            folder = uigetdir('Select a directory to save the data.');
            if(folder)
                save(join([folder,"\ParameterManager",app.Tag,".mat"],''), 'SavedRowNames', 'SavedTableData');
            end
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            [file,path] =  uigetfile('*.mat', 'Select the parameter manager save file.');
            if file
                load([path file], 'SavedRowNames', 'SavedTableData');
                app.UITable.RowName = SavedRowNames;
                app.UITable.Data = SavedTableData;
            else
                error('The selected file is invalid.');
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
         SavedTableData = app.UITable.Data;
            save(join(["ParameterManager",app.Tag,".mat"],''), 'SavedTableData');
            disp("Parameter Manager closing...");
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'; 'Column 4'};
            app.UITable.RowName = {'A,B,C,D'};
            app.HTML = uihtml(app.UIFigure);
            app.HTML.HTMLSource = '<p style="font-family:helvetica;font-size:10"> <b><u>Prefix</u></b><br/>Q &nbsp &nbsp  quetta &nbsp 10<sup>+30</sup><br />R &nbsp &nbsp  ronna  &nbsp 10<sup>+27</sup><br />Y &nbsp  &nbsp  yotta   &nbsp &nbsp 10<sup>+24</sup><br />Z &nbsp  &nbsp  zetta   &nbsp &nbsp 10<sup>+21</sup><br />E &nbsp  &nbsp  exa &nbsp &nbsp   &nbsp 10<sup>+18</sup><br />P &nbsp &nbsp  peta   &nbsp &nbsp 10<sup>+15</sup><br />T &nbsp  &nbsp  tera   &nbsp &nbsp &nbsp 10<sup>+12</sup><br />G &nbsp  &nbsp  giga  &nbsp &nbsp 10<sup>+9</sup><br />M &nbsp  &nbsp  mega   &nbsp 10<sup>+6</sup><br />k &nbsp  &nbsp  kilo &nbsp   &nbsp &nbsp 10<sup>+3</sup><br />h &nbsp &nbsp  hecto   &nbsp 10<sup>+2</sup><br />da  &nbsp  deca   &nbsp &nbsp 10<sup>+1</sup><br /> &nbsp &nbsp &nbsp &nbsp &nbsp &nbsp &nbsp &nbsp &nbsp &nbsp   10<sup>+0</sup><br />d &nbsp &nbsp  deci  &nbsp &nbsp &nbsp 10<sup>-1</sup><br />c &nbsp &nbsp  centi  &nbsp &nbsp &nbsp 10<sup>-2</sup><br />m &nbsp  &nbsp  milli   &nbsp &nbsp &nbsp 10<sup>-3</sup><br />&#956 &nbsp  &nbsp  micro   &nbsp &nbsp 10<sup>-6</sup><br />n &nbsp &nbsp  nano   &nbsp &nbsp 10<sup>-9</sup><br />p &nbsp &nbsp  pico   &nbsp &nbsp &nbsp 10<sup>-12</sup><br />f &nbsp  &nbsp  femto   &nbsp &nbsp 10<sup>-15</sup><br />a &nbsp  &nbsp  atto &nbsp  &nbsp &nbsp 10<sup>-18</sup><br />z &nbsp  &nbsp  zepto   &nbsp &nbsp 10<sup>-21</sup><br />y &nbsp  &nbsp  yocto   &nbsp &nbsp 10<sup>-24</sup><br />r &nbsp  &nbsp  ronto   &nbsp &nbsp 10<sup>-27</sup><br />q &nbsp  &nbsp  quecto   &nbsp 10<sup>-30</sup><br /></p>';
            app.KeeporModifytheentriesThenclosethewindowLabel = uilabel(app.UIFigure);
            app.KeeporModifytheentriesThenclosethewindowLabel.Text = 'Keep or Modify the entries. Then close the window.';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Confirmation(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end