classdef FigureAnalyser < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FigureAnalyserv11NaLyserv19DLCUIFigure  matlab.ui.Figure
        SelectthefiguretoanalysemodifyPanel  matlab.ui.container.Panel
        FilefigEditField          matlab.ui.control.EditField
        FilefigEditFieldLabel     matlab.ui.control.Label
        TabGroup                  matlab.ui.container.TabGroup
        ModifyTab                 matlab.ui.container.Tab
        GridLayout                matlab.ui.container.GridLayout
        WindowDropDown            matlab.ui.control.DropDown
        AxesSelectionDropDown     matlab.ui.control.DropDown
        ModifyButton              matlab.ui.control.Button
        AnalyseTab                matlab.ui.container.Tab
        GridLayout_2              matlab.ui.container.GridLayout
        FunctionApplierButton     matlab.ui.control.Button
        TangentIntersectorButton  matlab.ui.control.Button
        CurveIntersectorButton    matlab.ui.control.Button
        SelectButton              matlab.ui.control.Button
    end

    
    properties (Access = private)
        axesNumber;         % Selected axes
        maxAxesNumber       % Total number of axes
        figInspector;       % Figure Inspector Object
        graph;              % Graph Object
        windowDimensions    % Dimension of the figure window
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            disp('Launch of Figure Analyser (NaLyser v1.9) starting. Thank you for using this service.');
            app.graph = Graphs("Lone graph");
            app.graph.nbFigs = 1;
            app.FilefigEditField.Value = 'C:\';
                
        end

        % Button pushed function: SelectButton
        function SelectButtonPushed(app, event)
            prevValue = app.FilefigEditField.Value;
            try
                % Get the file
                if(strcmp(app.FilefigEditField.Value,'C:\'))
                    [file,path] = uigetfile('*.fig','Select your input files directory.');
                else
                    [file,path] = uigetfile('*.fig','Select your input files directory.',genpath(app.FilefigEditField.Value));
                end

                app.FilefigEditField.Value = fullfile(path, file);
                if isequal(file,0)
                    disp('User selected Cancel');
                else
                    disp(['User selected ', app.FilefigEditField.Value]);
                end

                % Get the axes
                app.graph.axesList = [];
                app.graph.figList{1} = openfig(app.FilefigEditField.Value,'reuse');
                h=get(gcf,'children');
                if(length(app.windowDimensions) ==4)
                    app.graph.figList{1}.Position = app.windowDimensions;
                end
                j = 0;
                for i = 1:length(h)
                    if(strcmp(get(h(i),'type'),'axes'))
                        j = j+1;
                        app.graph.axesList{1}(j) = h(i);
                    end
                end
                app.maxAxesNumber = j;
                app.AxesSelectionDropDown.Items = cellstr(num2str([1:j].'));
                app.AxesSelectionDropDown.Value = num2str(j);
                app.axesNumber = app.AxesSelectionDropDown.Value; 
            catch
                app.FilefigEditField.Value = prevValue;
            end
        end

        % Size changed function: SelectthefiguretoanalysemodifyPanel
        function SelectthefiguretoanalysemodifyPanelSizeChanged(app, event)
            position = app.SelectthefiguretoanalysemodifyPanel.Position;
            
        end

        % Button pushed function: ModifyButton
        function ModifyButtonPushed(app, event)
            try
                
                app.figInspector = FigureInspector(app.graph.axesList{1}(str2num(app.axesNumber)));
            catch
                disp('File not found.')
            end
        end

        % Button pushed function: CurveIntersectorButton
        function CurveIntersectorButtonPushed(app, event)
            try         
                h=get(gcf,'children');
                j = 1;
                for i = 1:length(h)
                    if(strcmp(get(h(i),'type'),'axes'))
                        app.graph.axesList{1}(j) = h(i);
                        j = j+1;
                    end
                end

                CurveIntersector(app.graph);
            catch
                disp('File not found.')
            end
        
        end

        % Button pushed function: TangentIntersectorButton
        function TangentIntersectorButtonPushed(app, event)
                h=get(gcf,'children');
                j = 1;
                for i = 1:length(h)
                    if(strcmp(get(h(i),'type'),'axes'))
                        app.graph.axesList{1}(j) = h(i);
                        j = j+1;
                    end
                end

                TangentIntersector(app.graph);
        end

        % Button pushed function: FunctionApplierButton
        function FunctionApplierButtonPushed(app, event)
                h=get(gcf,'children');
                j = 1;
                for i = 1:length(h)
                    if(strcmp(get(h(i),'type'),'axes'))
                        app.graph.axesList{1}(j) = h(i);
                        j = j+1;
                    end
                end

                FunctionApplyer(app.graph);
        end

        % Value changed function: AxesSelectionDropDown
        function AxesSelectionDropDownValueChanged(app, event)
            value = app.AxesSelectionDropDown.Value;
            app.axesNumber = value;
            try
                app.figInspector.closeApp()
            end
        end

        % Value changed function: WindowDropDown
        function WindowDropDownValueChanged(app, event)
            value = app.WindowDropDown.Value;
            set(0,'units','pixels');  
            
            % Get the screen dimensions (without the lower task bar for a standard Windows operating system)
            tmpf = figure('Units','pixels','Visible','on'); % open a figure, save the handles to a variable
            tmpf.WindowState = 'maximized'; % maximize the figure window
            waitfor(tmpf,'InnerPosition');
            Pix_SS = tmpf.InnerPosition;
            delete(tmpf);

            % Get the requested window size
            if(strcmp(value, 'Screensize'))
                app.windowDimensions = Pix_SS;
            elseif(strcmp(value, 'Half width')) 
                app.windowDimensions = [Pix_SS(1:2) Pix_SS(3)/2 Pix_SS(4)];
            elseif(strcmp(value, 'Half height'))
                app.windowDimensions = [Pix_SS(1:3) Pix_SS(4)/2];
            elseif(strcmp(value, 'Square'))
                if(Pix_SS(3) > Pix_SS(4))
                    app.windowDimensions = [Pix_SS(1:2) Pix_SS(4) Pix_SS(4)];
                else
                    app.windowDimensions = [Pix_SS(1:2) Pix_SS(3) Pix_SS(3)];
                end
            elseif(strcmp(value, 'Double square'))
                if(Pix_SS(3) > Pix_SS(4) * 2)
                    app.windowDimensions = [Pix_SS(1:2) Pix_SS(4)*2 Pix_SS(4)];
                else
                    app.windowDimensions = [Pix_SS(1:2) Pix_SS(3) round(Pix_SS(3)/2)];
                end
            elseif(strcmp(value, 'Rectangle'))
                GoldenRatio = 1.618033988749;
                if(Pix_SS(3) > Pix_SS(4) * GoldenRatio)
                    app.windowDimensions = [Pix_SS(1:2) round(Pix_SS(4)*GoldenRatio) Pix_SS(4)];
                else
                    app.windowDimensions = [Pix_SS(1:2) Pix_SS(3) round(Pix_SS(3)/GoldenRatio)];
                end
            elseif(strcmp(value, 'ISO A'))
                if(Pix_SS(3) > Pix_SS(4) * sqrt(2))
                    app.windowDimensions = [Pix_SS(1:2) round(Pix_SS(4)*sqrt(2)) Pix_SS(4)];
                else
                    app.windowDimensions = [Pix_SS(1:2) Pix_SS(3) round(Pix_SS(3)/sqrt(2))];
                end
            else
                Pix_Dim = str2num(value);
                if(length(Pix_Dim) == 2)
                    app.windowDimensions = [Pix_SS(1:2) Pix_Dim(1:2)];
                else
                    disp('Error. Windows dimensions are invalid.')
                    app.windowDimensions = Pix_SS;
                end
            end
            app.graph.figList{1}.Position = app.windowDimensions;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FigureAnalyserv11NaLyserv19DLCUIFigure = uifigure('Visible', 'off');
            app.FigureAnalyserv11NaLyserv19DLCUIFigure.Name = 'Figure Analyser v1.1 (NaLyser v1.9 DLC)';
            app.SelectthefiguretoanalysemodifyPanel = uipanel(app.FigureAnalyserv11NaLyserv19DLCUIFigure);
            app.SelectthefiguretoanalysemodifyPanel.Title = 'Select the figure to analyse/modify.';
            app.SelectthefiguretoanalysemodifyPanel.SizeChangedFcn = createCallbackFcn(app, @SelectthefiguretoanalysemodifyPanelSizeChanged, true);
            app.SelectButton = uibutton(app.SelectthefiguretoanalysemodifyPanel, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
            app.SelectButton.Text = 'Select';
            app.TabGroup = uitabgroup(app.SelectthefiguretoanalysemodifyPanel);
            app.ModifyTab = uitab(app.TabGroup);
            app.ModifyTab.Title = 'Modify';
            app.GridLayout = uigridlayout(app.ModifyTab);
            app.ModifyButton = uibutton(app.GridLayout, 'push');
            app.ModifyButton.ButtonPushedFcn = createCallbackFcn(app, @ModifyButtonPushed, true);
            app.ModifyButton.Text = 'Modify';
            app.AxesSelectionDropDown = uidropdown(app.GridLayout);
            app.AxesSelectionDropDown.ValueChangedFcn = createCallbackFcn(app, @AxesSelectionDropDownValueChanged, true);
            app.WindowDropDown = uidropdown(app.GridLayout);
            app.WindowDropDown.Items = {'Screensize', 'Half width', 'Half height', 'Square', 'Double square', 'Rectangle', 'ISO A', 'Custom (XX XX)'};
            app.WindowDropDown.Editable = 'on';
            app.WindowDropDown.ValueChangedFcn = createCallbackFcn(app, @WindowDropDownValueChanged, true);
            app.WindowDropDown.Value = 'Screensize';
            app.AnalyseTab = uitab(app.TabGroup);
            app.AnalyseTab.Title = 'Analyse';
            app.GridLayout_2 = uigridlayout(app.AnalyseTab);
            app.CurveIntersectorButton = uibutton(app.GridLayout_2, 'push');
            app.CurveIntersectorButton.ButtonPushedFcn = createCallbackFcn(app, @CurveIntersectorButtonPushed, true);
            app.CurveIntersectorButton.Text = 'Curve Intersector';
            app.TangentIntersectorButton = uibutton(app.GridLayout_2, 'push');
            app.TangentIntersectorButton.ButtonPushedFcn = createCallbackFcn(app, @TangentIntersectorButtonPushed, true);
            app.TangentIntersectorButton.Text = 'Tangent Intersector';
            app.FunctionApplierButton = uibutton(app.GridLayout_2, 'push');
            app.FunctionApplierButton.ButtonPushedFcn = createCallbackFcn(app, @FunctionApplierButtonPushed, true);
            app.FunctionApplierButton.Text = 'Function Applier';
            app.FilefigEditFieldLabel = uilabel(app.SelectthefiguretoanalysemodifyPanel);
            app.FilefigEditFieldLabel.Text = 'File (.fig)';
            app.FilefigEditField = uieditfield(app.SelectthefiguretoanalysemodifyPanel, 'text');
            app.FilefigEditField.Editable = 'off';
            % Show the figure after all components are created
            app.FigureAnalyserv11NaLyserv19DLCUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FigureAnalyser

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FigureAnalyserv11NaLyserv19DLCUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FigureAnalyserv11NaLyserv19DLCUIFigure)
        end
    end
end