classdef FigureInspector < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        FontTab                      matlab.ui.container.Tab
        InterpreterDropDown          matlab.ui.control.DropDown
        InterpreterDropDownLabel     matlab.ui.control.Label
        FontNameDropDown             matlab.ui.control.DropDown
        FontNameDropDownLabel        matlab.ui.control.Label
        SubtitleBoldCheckBox         matlab.ui.control.CheckBox
        TitleBoldCheckBox            matlab.ui.control.CheckBox
        TitleFontSizeMultiplierEditField  matlab.ui.control.NumericEditField
        LabelFontSizeMultiplierEditField_2Label  matlab.ui.control.Label
        LabelFontSizeMultiplierEditField  matlab.ui.control.NumericEditField
        LabelFontSizeMultiplierEditFieldLabel  matlab.ui.control.Label
        ItalicCheckBox               matlab.ui.control.CheckBox
        BoldCheckBox                 matlab.ui.control.CheckBox
        FontSizeEditField            matlab.ui.control.NumericEditField
        FontSizeEditFieldLabel       matlab.ui.control.Label
        TicksTab                     matlab.ui.container.Tab
        ScaleDropDown_2              matlab.ui.control.DropDown
        ScaleDropDown_2Label         matlab.ui.control.Label
        ScaleDropDown                matlab.ui.control.DropDown
        ScaleDropDownLabel           matlab.ui.control.Label
        EndEditField_2               matlab.ui.control.NumericEditField
        EndEditField_2Label          matlab.ui.control.Label
        StepEditField_2              matlab.ui.control.NumericEditField
        StepEditField_2Label         matlab.ui.control.Label
        StartEditField_2             matlab.ui.control.NumericEditField
        StartEditField_2Label        matlab.ui.control.Label
        YaxisLabel                   matlab.ui.control.Label
        EndEditField                 matlab.ui.control.NumericEditField
        EndEditFieldLabel            matlab.ui.control.Label
        StepEditField                matlab.ui.control.NumericEditField
        StepEditFieldLabel           matlab.ui.control.Label
        XaxisLabel                   matlab.ui.control.Label
        StartEditField               matlab.ui.control.NumericEditField
        StartEditFieldLabel          matlab.ui.control.Label
        GridsTab                     matlab.ui.container.Tab
        GridminorCheckBox            matlab.ui.control.CheckBox
        GridCheckBox                 matlab.ui.control.CheckBox
        LabelsTab                    matlab.ui.container.Tab
        YLabelEditField              matlab.ui.control.EditField
        XLabelEditField_2Label       matlab.ui.control.Label
        XLabelEditField              matlab.ui.control.EditField
        XLabelEditFieldLabel         matlab.ui.control.Label
        SubtitleEditField            matlab.ui.control.EditField
        SubtitleEditFieldLabel       matlab.ui.control.Label
        TitleEditField               matlab.ui.control.EditField
        TitleEditFieldLabel          matlab.ui.control.Label
        LegendTab                    matlab.ui.container.Tab
        CreateDestroyLegendCheckBox  matlab.ui.control.CheckBox
    end

    
    properties (Access = private)
        figureAxe % Description
    end
    
    methods (Access = private)
        
        function rescale(app)
            axis(app.figureAxe, [app.StartEditField.Value app.EndEditField.Value app.StartEditField_2.Value app.EndEditField_2.Value]);
        end
    end
    
    methods (Access = public)
        
        function results = closeApp(app)
            delete(app.UIFigure)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, figureAxe)
            app.FontSizeEditField.Value = figureAxe.FontSize;
            app.BoldCheckBox.Value = strcmp(figureAxe.FontWeight, 'bold');
            app.ItalicCheckBox.Value = strcmp(figureAxe.FontAngle, 'italic');
            app.LabelFontSizeMultiplierEditField.Value = figureAxe.LabelFontSizeMultiplier;
            app.TitleFontSizeMultiplierEditField.Value = figureAxe.TitleFontSizeMultiplier
            app.TitleBoldCheckBox.Value = strcmp(figureAxe.TitleFontWeight, 'bold');
            app.SubtitleBoldCheckBox.Value = strcmp(figureAxe.SubtitleFontWeight, 'bold');
            
            app.InterpreterDropDown.Value = figureAxe.XLabel.Interpreter;
            
                app.FontNameDropDown.Items = {'Agency FB', 'Algerian', 'Arial', 'Arial Black', 'Arial Narrow', 'Arial Rounded MT Bold', 'AvantGarde', 'Bahnschrift', 'Baskerville Old Face', 'Batang', 'BatangChe', 'Bauhaus 93', 'Bell MT', 'Berlin Sans FB', 'Berlin Sans FB Demi', 'Bernard MT Condensed', 'BIZ UDGothic', 'BIZ UDMicho Medium', 'BIZ UDPGothic', 'BIZ UDPMincho Medium', 'Blackadder ITC', 'Bodoni MT', 'Bodoni MT Black', 'Bodoni MT Condensed', 'Bodoni MT Poster Compressed', 'Book Antiqua', 'Bookman', 'Bookman Old Style', 'Bradley Hand ITC', 'Britannic Bold', 'Broadway', 'Brush Script MT', 'Calibri', 'Calibri Light', 'Californian FB', 'Calisto MT', 'Cambria', 'Cambria Math', 'Candara', 'Candara Light', 'Cascadia Code', 'Cascadia Mono', 'Castellar', 'Centaur', 'Century', 'Century Gothic', 'Century Schoolbook', 'Chiller', 'Colonna MT', 'Comic Sans MS', 'Consolas', 'Constantia', 'Cooper Black', 'Copperplate Gothic Bold', 'Copperplate Gothic Light', 'Corbel', 'Corbel Light', 'Courier', 'Courier New', 'Curlz MT', 'Dialog', 'DialogInput', 'Dotum', 'DotumChe', 'Dubai', 'Dubai Light', 'Dubai Medium', 'Ebrima', 'Edwardian Script ITC', 'Elephant', 'Engravers MT', 'Eras Bold ITC', 'Eras Demi ITC', 'Eras Light ITC', 'Eras Medium ITC', 'Felix Titling', 'Footlight MT Light', 'Forte', 'Franklin Gothic Book', 'Franklin Gothic Demi', 'Franklin Gothic Demi Cond', 'Franklin Gothic Heavy', 'Franklin Gothic Medium', 'Franklin Gothic Medium Cond', 'Freestyle Script', 'French Script MT', 'Gabriola', 'Gadugi', 'Garamond', 'Georgia', 'Gigi', 'Gill Sans MT', 'Gill Sans MT Condensed', 'Gill Sans MT Ext Condensed Bold', 'Gill Sans Ultra Bold', 'Gill Sans Ultra Bold Condensed', 'Gloucester MT Extra Condensed', 'Goudy Old Style', 'Goudy Stout', 'Gulim', 'GulimChe', 'Gungsuh', 'GungsuhChe', 'Haettenschweiler', 'Harlow Solid Italic', 'Harrington', 'Helvetica', 'Helvetica-Narrow', 'High Tower Text', 'Impact', 'Imprint MT Shadow', 'Informal Roman', 'Ink Free', 'Javanese Text', 'Jokerman', 'Juice ITC', 'Kristen ITC', 'Kunstler Script', 'Leelawadee', 'Leelawadee UI', 'Leelawadee UI Semilight', 'Lucida Bright', 'Lucida Calligraphy', 'Lucida Console', 'Lucida Fax', 'Lucida Handwriting', 'Lucida Sans', 'Lucida Sans Typewriter', 'Lucida Sans Unicode', 'Magneto', 'Maiandra GD', 'Malgun Gothic', 'Malgun Gothic Semilight', 'Matura MT Script Capitals', 'Meiryo', 'Meiryo UI', 'Microsoft Himalaya', 'Microsoft JhengHei', 'Microsoft JhengHei Light', 'Microsoft JhengHei UI', 'Microsoft JhengHei UI Light', 'Microsoft New Tai Lue', 'Microsoft PhagsPa', 'Microsoft Sans Serif', 'Microsoft Tai Le', 'Microsoft Uighur', 'Microsoft YaHei', 'Microsoft YaHei Light', 'Microsoft YaHei UI', 'Microsoft YaHei UI Light', 'Microsoft Yi Baiti', 'MingLiU-ExtB', 'MingLiU_HKSCS-ExtB', 'Mistral', 'Modern No. 20', 'Mongolian Baiti', 'Monospaced', 'Monotype Corsiva', 'MS Gothic', 'MS Mincho', 'MS PGothic', 'MS PMicho', 'MS Reference Sans Serif', 'MS UI Gothic', 'MV Boli', 'Myanmar Text', 'NewCenturySchoolBook', 'Niagara Engraved', 'Niagara Solid', 'Nirmala UI', 'Nirmala UI Semilight', 'NSimSun', 'OCR A Extended', 'Old English Text MT', 'Onyx', 'Palace Script MT', 'Palatino', 'Palatino Linotype', 'Papyrus', 'Parchment', 'Perpetua', 'Perpetua Tittling MT', 'Playbill', 'PMingLiU_ExtB', 'Poor Richard', 'Pristina', 'Rage Italic', 'Ravie', 'Rockwell', 'Rockwell Condensed', 'Rockwell Extra Bold', 'SansSerif', 'Script MT Bold', 'Segoe Print', 'Segoe Script', 'Segoe UI', 'Segoe UI Black', 'Segoe UI Emoji', 'Segoe UI Historic', 'Segoe UI Light', 'Segoe UI Semibold', 'Segoe UI Semilight', 'Segoe UI Symbol', 'Segoe UI Variable', 'Serif', 'Showcard Gothic', 'SimSun', 'SimSun-ExtB', 'Sitka Text', 'Snap ITC', 'Stencil', 'Sylfaen', 'Symbol', 'Tahoma', 'Tempus Sans ITC', 'Times', 'Times New Roman', 'Trebuchet MS', 'Tw Cen MT', 'Tw Cen MT Condensed', 'Tw Cen MT Condensed Extra Bold', 'UD Digi Kyokasho N-B', 'UD Digi Kyokasho N-R', 'UD Digi Kyokasho NK-B', 'UD Digi Kyokasho NK-R', 'UD Digi Kyokasho NP-B', 'UD Digi Kyokasho NP-R', 'Verdana', 'Viner Hand ITC', 'Vivaldi', 'Vladimir Script', 'Wide Latin', 'Yu Gothic', 'Yu Gothic Light', 'Yu Gothic Medium', 'Yu Gothic UI', 'Yu Gothic UI Light', 'Yu Gothic Semibold', 'Yu Gothic Semilight', 'Yu Mincho', 'Yu Mincho Demibold', 'Yu Mincho Light', 'ZapfChancery', 'ZapfDingbats'};
                app.FontNameDropDown.ItemsData = {'Agency FB', 'Algerian', 'Arial', 'Arial Black', 'Arial Narrow', 'Arial Rounded MT Bold', 'AvantGarde', 'Bahnschrift', 'Baskerville Old Face', 'Batang', 'BatangChe', 'Bauhaus 93', 'Bell MT', 'Berlin Sans FB', 'Berlin Sans FB Demi', 'Bernard MT Condensed', 'BIZ UDGothic', 'BIZ UDMicho Medium', 'BIZ UDPGothic', 'BIZ UDPMincho Medium', 'Blackadder ITC', 'Bodoni MT', 'Bodoni MT Black', 'Bodoni MT Condensed', 'Bodoni MT Poster Compressed', 'Book Antiqua', 'Bookman', 'Bookman Old Style', 'Bradley Hand ITC', 'Britannic Bold', 'Broadway', 'Brush Script MT', 'Calibri', 'Calibri Light', 'Californian FB', 'Calisto MT', 'Cambria', 'Cambria Math', 'Candara', 'Candara Light', 'Cascadia Code', 'Cascadia Mono', 'Castellar', 'Centaur', 'Century', 'Century Gothic', 'Century Schoolbook', 'Chiller', 'Colonna MT', 'Comic Sans MS', 'Consolas', 'Constantia', 'Cooper Black', 'Copperplate Gothic Bold', 'Copperplate Gothic Light', 'Corbel', 'Corbel Light', 'Courier', 'Courier New', 'Curlz MT', 'Dialog', 'DialogInput', 'Dotum', 'DotumChe', 'Dubai', 'Dubai Light', 'Dubai Medium', 'Ebrima', 'Edwardian Script ITC', 'Elephant', 'Engravers MT', 'Eras Bold ITC', 'Eras Demi ITC', 'Eras Light ITC', 'Eras Medium ITC', 'Felix Titling', 'Footlight MT Light', 'Forte', 'Franklin Gothic Book', 'Franklin Gothic Demi', 'Franklin Gothic Demi Cond', 'Franklin Gothic Heavy', 'Franklin Gothic Medium', 'Franklin Gothic Medium Cond', 'Freestyle Script', 'French Script MT', 'Gabriola', 'Gadugi', 'Garamond', 'Georgia', 'Gigi', 'Gill Sans MT', 'Gill Sans MT Condensed', 'Gill Sans MT Ext Condensed Bold', 'Gill Sans Ultra Bold', 'Gill Sans Ultra Bold Condensed', 'Gloucester MT Extra Condensed', 'Goudy Old Style', 'Goudy Stout', 'Gulim', 'GulimChe', 'Gungsuh', 'GungsuhChe', 'Haettenschweiler', 'Harlow Solid Italic', 'Harrington', 'Helvetica', 'Helvetica-Narrow', 'High Tower Text', 'Impact', 'Imprint MT Shadow', 'Informal Roman', 'Ink Free', 'Javanese Text', 'Jokerman', 'Juice ITC', 'Kristen ITC', 'Kunstler Script', 'Leelawadee', 'Leelawadee UI', 'Leelawadee UI Semilight', 'Lucida Bright', 'Lucida Calligraphy', 'Lucida Console', 'Lucida Fax', 'Lucida Handwriting', 'Lucida Sans', 'Lucida Sans Typewriter', 'Lucida Sans Unicode', 'Magneto', 'Maiandra GD', 'Malgun Gothic', 'Malgun Gothic Semilight', 'Matura MT Script Capitals', 'Meiryo', 'Meiryo UI', 'Microsoft Himalaya', 'Microsoft JhengHei', 'Microsoft JhengHei Light', 'Microsoft JhengHei UI', 'Microsoft JhengHei UI Light', 'Microsoft New Tai Lue', 'Microsoft PhagsPa', 'Microsoft Sans Serif', 'Microsoft Tai Le', 'Microsoft Uighur', 'Microsoft YaHei', 'Microsoft YaHei Light', 'Microsoft YaHei UI', 'Microsoft YaHei UI Light', 'Microsoft Yi Baiti', 'MingLiU-ExtB', 'MingLiU_HKSCS-ExtB', 'Mistral', 'Modern No. 20', 'Mongolian Baiti', 'Monospaced', 'Monotype Corsiva', 'MS Gothic', 'MS Mincho', 'MS PGothic', 'MS PMicho', 'MS Reference Sans Serif', 'MS UI Gothic', 'MV Boli', 'Myanmar Text', 'NewCenturySchoolBook', 'Niagara Engraved', 'Niagara Solid', 'Nirmala UI', 'Nirmala UI Semilight', 'NSimSun', 'OCR A Extended', 'Old English Text MT', 'Onyx', 'Palace Script MT', 'Palatino', 'Palatino Linotype', 'Papyrus', 'Parchment', 'Perpetua', 'Perpetua Tittling MT', 'Playbill', 'PMingLiU_ExtB', 'Poor Richard', 'Pristina', 'Rage Italic', 'Ravie', 'Rockwell', 'Rockwell Condensed', 'Rockwell Extra Bold', 'SansSerif', 'Script MT Bold', 'Segoe Print', 'Segoe Script', 'Segoe UI', 'Segoe UI Black', 'Segoe UI Emoji', 'Segoe UI Historic', 'Segoe UI Light', 'Segoe UI Semibold', 'Segoe UI Semilight', 'Segoe UI Symbol', 'Segoe UI Variable', 'Serif', 'Showcard Gothic', 'SimSun', 'SimSun-ExtB', 'Sitka Text', 'Snap ITC', 'Stencil', 'Sylfaen', 'Symbol', 'Tahoma', 'Tempus Sans ITC', 'Times', 'Times New Roman', 'Trebuchet MS', 'Tw Cen MT', 'Tw Cen MT Condensed', 'Tw Cen MT Condensed Extra Bold', 'UD Digi Kyokasho N-B', 'UD Digi Kyokasho N-R', 'UD Digi Kyokasho NK-B', 'UD Digi Kyokasho NK-R', 'UD Digi Kyokasho NP-B', 'UD Digi Kyokasho NP-R', 'Verdana', 'Viner Hand ITC', 'Vivaldi', 'Vladimir Script', 'Wide Latin', 'Yu Gothic', 'Yu Gothic Light', 'Yu Gothic Medium', 'Yu Gothic UI', 'Yu Gothic UI Light', 'Yu Gothic Semibold', 'Yu Gothic Semilight', 'Yu Mincho', 'Yu Mincho Demibold', 'Yu Mincho Light', 'ZapfChancery', 'ZapfDingbats '};
                try
                    app.FontNameDropDown.Value = figureAxe.FontName;
                catch
                    app.FontNameDropDown.Value = 'Times New Roman';
            
                end
             app.TitleEditField.Value = figureAxe.Title.String;
             app.SubtitleEditField.Value = figureAxe.Subtitle.String;
             app.XLabelEditField.Value = figureAxe.XLabel.String;
             app.YLabelEditField.Value = figureAxe.YLabel.String;
             
             app.ScaleDropDown.Value = figureAxe.XScale;
             app.StartEditField.Value = figureAxe.XTick(1);
             if(strcmp(figureAxe.XScale, 'linear'))
                app.StepEditField.Value = max(diff(figureAxe.XTick));
             else
                app.StepEditField.Value = max(figureAxe.XTick(2:end)./figureAxe.XTick(1:end-1));
             end
             app.EndEditField.Value = figureAxe.XTick(end);

             app.ScaleDropDown_2.Value = figureAxe.YScale;
             app.StartEditField_2.Value = figureAxe.YTick(1);
             if(strcmp(figureAxe.YScale, 'linear'))
                app.StepEditField_2.Value = max(diff(figureAxe.YTick));
             else
                app.StepEditField_2.Value = max(figureAxe.YTick(2:end)./figureAxe.YTick(1:end-1));
             end
             app.EndEditField_2.Value = figureAxe.YTick(end);

            app.figureAxe = figureAxe;

            app.GridCheckBox.Value = (app.figureAxe.XGrid)|(app.figureAxe.YGrid)|(app.figureAxe.ZGrid);
            app.GridminorCheckBox.Value = (app.figureAxe.XMinorGrid)|(app.figureAxe.YMinorGrid)|(app.figureAxe.ZMinorGrid);

            app.CreateDestroyLegendCheckBox.Value = ~isempty(app.figureAxe.Legend);

        end

        % Value changed function: FontSizeEditField
        function FontSizeEditFieldValueChanged(app, event)
            value = app.FontSizeEditField.Value;
            app.figureAxe.FontSize = value;
        end

        % Value changed function: BoldCheckBox
        function BoldCheckBoxValueChanged(app, event)
            value = app.BoldCheckBox.Value;
            if(value)
                app.figureAxe.FontWeight = 'bold';
            else
                app.figureAxe.FontWeight = 'normal';
            end
        end

        % Value changed function: ItalicCheckBox
        function ItalicCheckBoxValueChanged(app, event)
            value = app.ItalicCheckBox.Value;
            if(value)
                app.figureAxe.FontAngle = 'italic';
            else
                app.figureAxe.FontAngle = 'normal';
            end
        end

        % Value changed function: LabelFontSizeMultiplierEditField
        function LabelFontSizeMultiplierEditFieldValueChanged(app, event)
            value = app.LabelFontSizeMultiplierEditField.Value;
            app.figureAxe.LabelFontSizeMultiplier = value;
        end

        % Value changed function: TitleFontSizeMultiplierEditField
        function TitleFontSizeMultiplierEditFieldValueChanged(app, event)
            value = app.TitleFontSizeMultiplierEditField.Value;
            app.figureAxe.TitleFontSizeMultiplier = value;
        end

        % Value changed function: TitleBoldCheckBox
        function TitleBoldCheckBoxValueChanged(app, event)
            value = app.TitleBoldCheckBox.Value;
            if(value)
                app.figureAxe.TitleFontWeight = 'bold';
            else
                app.figureAxe.TitleFontWeight = 'normal';
            end
        end

        % Value changed function: SubtitleBoldCheckBox
        function SubtitleBoldCheckBoxValueChanged(app, event)
            value = app.SubtitleBoldCheckBox.Value;
            if(value)
                app.figureAxe.SubtitleFontWeight = 'bold';
            else
                app.figureAxe.SubtitleFontWeight = 'normal';
            end
        end

        % Value changed function: FontNameDropDown
        function FontNameDropDownValueChanged(app, event)
            value = app.FontNameDropDown.Value;
            app.figureAxe.FontName = value;
        end

        % Value changed function: InterpreterDropDown
        function InterpreterDropDownValueChanged(app, event)
            value = app.InterpreterDropDown.Value;
            app.figureAxe.XLabel.Interpreter = value;
            app.figureAxe.YLabel.Interpreter = value;
            app.figureAxe.Title.Interpreter = value;
            
        end

        % Value changed function: TitleEditField
        function TitleEditFieldValueChanged(app, event)
            value = app.TitleEditField.Value;
            app.figureAxe.Title.String = value;
        end

        % Value changed function: SubtitleEditField
        function SubtitleEditFieldValueChanged(app, event)
            value = app.SubtitleEditField.Value;
            app.figureAxe.Subtitle.String = value;
        end

        % Value changed function: XLabelEditField
        function XLabelEditFieldValueChanged(app, event)
            value = app.XLabelEditField.Value;
            app.figureAxe.XLabel.String = value;
        end

        % Value changed function: YLabelEditField
        function YLabelEditFieldValueChanged(app, event)
            value = app.YLabelEditField.Value;
            app.figureAxe.YLabel.String = value;
        end

        % Value changed function: ScaleDropDown
        function ScaleDropDownValueChanged(app, event)
            value = app.ScaleDropDown.Value;
            app.figureAxe.XScale = value;
            app.StartEditField.Value = app.figureAxe.XTick(1);
             if(strcmp(value, 'linear'))
                app.StepEditField.Value = max(diff(app.figureAxe.XTick));
             else
                app.StepEditField.Value = max(app.figureAxe.XTick(2:end)./app.figureAxe.XTick(1:end-1));
             end
             app.EndEditField.Value = app.figureAxe.XTick(end);
        end

        % Value changed function: ScaleDropDown_2
        function ScaleDropDown_2ValueChanged(app, event)
            value = app.ScaleDropDown_2.Value;
            app.figureAxe.YScale = value;
            app.StartEditField_2.Value = app.figureAxe.YTick(1);
             if(strcmp(value, 'linear'))
                app.StepEditField_2.Value = max(diff(app.figureAxe.YTick));
             else
                app.StepEditField_2.Value = max(app.figureAxe.YTick(2:end)./app.figureAxe.YTick(1:end-1));
             end
             app.EndEditField_2.Value = app.figureAxe.YTick(end);
        end

        % Value changed function: StartEditField
        function StartEditFieldValueChanged(app, event)
            value = app.StartEditField.Value;
            if(strcmp(app.ScaleDropDown.Value, 'linear'))
                app.figureAxe.XTick = value:app.StepEditField.Value:app.EndEditField.Value;
            else
                app.figureAxe.XTick = app.StepEditField.Value^floor(log10(value)/log10(app.StepEditField.Value)) * app.StepEditField.Value.^(0:ceil(log10(app.EndEditField.Value/value)/log10(app.StepEditField.Value)));
            end
            app.rescale();
        end

        % Value changed function: StepEditField
        function StepEditFieldValueChanged(app, event)
            value = app.StepEditField.Value;
            if(strcmp(app.ScaleDropDown.Value, 'linear'))
                app.figureAxe.XTick = app.StartEditField.Value:value:app.EndEditField.Value;
            else
                app.figureAxe.XTick = value^floor(log10(app.StartEditField.Value)/log10(value)) * value.^(0:ceil(log10(app.EndEditField.Value/app.StartEditField.Value)/log10(value)));
            end
            app.rescale();
        end

        % Value changed function: EndEditField
        function EndEditFieldValueChanged(app, event)
            value = app.EndEditField.Value;
            if(strcmp(app.ScaleDropDown.Value, 'linear'))
                app.figureAxe.XTick = app.StartEditField.Value:app.StepEditField.Value:value;
            else
                app.figureAxe.XTick = app.StepEditField.Value^floor(log10(app.StartEditField.Value/log10(app.StepEditField.Value))) * app.StepEditField.Value.^(0:ceil(log10(value/app.StartEditField.Value)/log10(app.StepEditField.Value)));
            end
            app.rescale();
        end

        % Value changed function: StartEditField_2
        function StartEditField_2ValueChanged(app, event)
            value = app.StartEditField_2.Value;
            if(strcmp(app.ScaleDropDown_2.Value, 'linear'))
                app.figureAxe.YTick = value:app.StepEditField_2.Value:app.EndEditField_2.Value;
            else
                app.figureAxe.YTick = app.StepEditField_2.Value^floor(log10(value)/log10(app.StepEditField_2.Value)) * app.StepEditField_2.Value.^(0:ceil(log10(app.EndEditField_2.Value/value)/log10(app.StepEditField_2.Value)));
            end
            app.rescale();
        end

        % Value changed function: StepEditField_2
        function StepEditField_2ValueChanged(app, event)
            value = app.StepEditField_2.Value;
            if(strcmp(app.ScaleDropDown_2.Value, 'linear'))
                app.figureAxe.YTick = app.StartEditField_2.Value:value:app.EndEditField_2.Value;
            else
                app.figureAxe.YTick = value^floor(log10(app.StartEditField_2.Value)/log10(value)) * value.^(0:ceil(log10(app.EndEditField_2.Value/app.StartEditField_2.Value)/log10(value)));
            end
            app.rescale();
        end

        % Value changed function: EndEditField_2
        function EndEditField_2ValueChanged(app, event)
            value = app.EndEditField_2.Value;
            if(strcmp(app.ScaleDropDown_2.Value, 'linear'))
                app.figureAxe.YTick = app.StartEditField_2.Value:app.StepEditField_2.Value:value;
            else
                app.figureAxe.YTick = app.StepEditField_2.Value^floor(log10(app.StartEditField_2.Value/log10(app.StepEditField_2.Value))) * app.StepEditField_2.Value.^(0:ceil(log10(value/app.StartEditField_2.Value)/log10(app.StepEditField_2.Value)));
            end
            app.rescale();
        end

        % Value changed function: GridCheckBox
        function GridCheckBoxValueChanged(app, event)
            value = app.GridCheckBox.Value;
            if(value)
                app.figureAxe.XGrid = 1;
                app.figureAxe.YGrid = 1;
                app.figureAxe.ZGrid = 1;
            else
                app.figureAxe.XGrid = 0;
                app.figureAxe.YGrid = 0;
                app.figureAxe.ZGrid = 0;
                app.figureAxe.XMinorGrid = 0;
                app.figureAxe.YMinorGrid = 0;
                app.figureAxe.ZMinorGrid = 0;
                app.GridminorCheckBox.Value = 0;
            end
        end

        % Value changed function: GridminorCheckBox
        function GridminorCheckBoxValueChanged(app, event)
            value = app.GridminorCheckBox.Value;
            if(value)
                app.figureAxe.XMinorGrid = 1;
                app.figureAxe.YMinorGrid = 1;
                app.figureAxe.ZMinorGrid = 1;
                app.figureAxe.XGrid = 1;
                app.figureAxe.YGrid = 1;
                app.figureAxe.ZGrid = 1;
                app.GridCheckBox.Value = 1;
            else                
                app.figureAxe.XMinorGrid = 0;
                app.figureAxe.YMinorGrid = 0;
                app.figureAxe.ZMinorGrid = 0;
            end
        end

        % Value changed function: CreateDestroyLegendCheckBox
        function CreateDestroyLegendCheckBoxValueChanged(app, event)
            value = app.CreateDestroyLegendCheckBox.Value;
            if(value)
                legend(app.figureAxe);
            else
                legend(app.figureAxe,'off');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.FontTab = uitab(app.TabGroup);
            app.FontTab.Title = 'Font';
            app.FontSizeEditFieldLabel = uilabel(app.FontTab);
            app.FontSizeEditFieldLabel.Text = 'FontSize';
            app.FontSizeEditField = uieditfield(app.FontTab, 'numeric');
            app.FontSizeEditField.Limits = [0.5 Inf];
            app.FontSizeEditField.ValueChangedFcn = createCallbackFcn(app, @FontSizeEditFieldValueChanged, true);
            app.FontSizeEditField.Value = 10;
            app.BoldCheckBox = uicheckbox(app.FontTab);
            app.BoldCheckBox.ValueChangedFcn = createCallbackFcn(app, @BoldCheckBoxValueChanged, true);
            app.BoldCheckBox.Text = 'Bold';
            app.ItalicCheckBox = uicheckbox(app.FontTab);
            app.ItalicCheckBox.ValueChangedFcn = createCallbackFcn(app, @ItalicCheckBoxValueChanged, true);
            app.ItalicCheckBox.Text = 'Italic';
            app.LabelFontSizeMultiplierEditFieldLabel = uilabel(app.FontTab);
            app.LabelFontSizeMultiplierEditFieldLabel.Text = 'LabelFontSizeMultiplier';
            app.LabelFontSizeMultiplierEditField = uieditfield(app.FontTab, 'numeric');
            app.LabelFontSizeMultiplierEditField.Limits = [0.001 Inf];
            app.LabelFontSizeMultiplierEditField.ValueChangedFcn = createCallbackFcn(app, @LabelFontSizeMultiplierEditFieldValueChanged, true);
            app.LabelFontSizeMultiplierEditField.Value = 1.1;
            app.LabelFontSizeMultiplierEditField_2Label = uilabel(app.FontTab);
            app.LabelFontSizeMultiplierEditField_2Label.Text = 'TitleFontSizeMultiplier';
            app.TitleFontSizeMultiplierEditField = uieditfield(app.FontTab, 'numeric');
            app.TitleFontSizeMultiplierEditField.Limits = [0.001 Inf];
            app.TitleFontSizeMultiplierEditField.ValueChangedFcn = createCallbackFcn(app, @TitleFontSizeMultiplierEditFieldValueChanged, true);
            app.TitleFontSizeMultiplierEditField.Value = 1.1;
            app.TitleBoldCheckBox = uicheckbox(app.FontTab);
            app.TitleBoldCheckBox.ValueChangedFcn = createCallbackFcn(app, @TitleBoldCheckBoxValueChanged, true);
            app.TitleBoldCheckBox.Text = 'TitleBold';
            app.SubtitleBoldCheckBox = uicheckbox(app.FontTab);
            app.SubtitleBoldCheckBox.ValueChangedFcn = createCallbackFcn(app, @SubtitleBoldCheckBoxValueChanged, true);
            app.SubtitleBoldCheckBox.Text = 'SubtitleBold';
            app.FontNameDropDownLabel = uilabel(app.FontTab);
            app.FontNameDropDownLabel.Text = 'FontName';
            app.FontNameDropDown = uidropdown(app.FontTab);
            app.FontNameDropDown.Items = {'Agency FB', 'Algerian', 'Arial', 'Arial Black', 'Arial Narrow', 'Arial Rounded MT Bold', 'AvantGarde', 'Bahnschrift', 'Baskerville Old Face', 'Batang', 'BatangChe', 'Bauhaus 93', 'Bell MT', 'Berlin Sans FB', 'Berlin Sans FB Demi', 'Bernard MT Condensed', 'BIZ UDGothic', 'BIZ UDMicho Medium', 'BIZ UDPGothic', 'BIZ UDPMincho Medium', 'Blackadder ITC', 'Bodoni MT', 'Bodoni MT Black', 'Bodoni MT Condensed', 'Bodoni MT Poster Compressed', 'Book Antiqua', 'Bookman', 'Bookman Old Style', 'Bradley Hand ITC', 'Britannic Bold', 'Broadway', 'Brush Script MT', 'Calibri', 'Calibri Light', 'Californian FB', 'Calisto MT', 'Cambria', 'Cambria Math', 'Candara', 'Candara Light', 'Cascadia Code', 'Cascadia Mono', 'Castellar', 'Centaur', 'Century', 'Century Gothic', 'Century Schoolbook', 'Chiller', 'Colonna MT', 'Comic Sans MS', 'Consolas', 'Constantia', 'Cooper Black', 'Copperplate Gothic Bold', 'Copperplate Gothic Light', 'Corbel', 'Corbel Light', 'Courier', 'Courier New', 'Curlz MT', 'Dialog', 'DialogInput', 'Dotum', 'DotumChe', 'Dubai', 'Dubai Light', 'Dubai Medium', 'Ebrima', 'Edwardian Script ITC', 'Elephant', 'Engravers MT', 'Eras Bold ITC', 'Eras Demi ITC', 'Eras Light ITC', 'Eras Medium ITC', 'Felix Titling', 'Footlight MT Light', 'Forte', 'Franklin Gothic Book', 'Franklin Gothic Demi', 'Franklin Gothic Demi Cond', 'Franklin Gothic Heavy', 'Franklin Gothic Medium', 'Franklin Gothic Medium Cond', 'Freestyle Script', 'French Script MT', 'Gabriola', 'Gadugi', 'Garamond', 'Georgia', 'Gigi', 'Gill Sans MT', 'Gill Sans MT Condensed', 'Gill Sans MT Ext Condensed Bold', 'Gill Sans Ultra Bold', 'Gill Sans Ultra Bold Condensed', 'Gloucester MT Extra Condensed', 'Goudy Old Style', 'Goudy Stout', 'Gulim', 'GulimChe', 'Gungsuh', 'GungsuhChe', 'Haettenschweiler', 'Harlow Solid Italic', 'Harrington', 'Helvetica', 'Helvetica-Narrow', 'High Tower Text', 'Impact', 'Imprint MT Shadow', 'Informal Roman', 'Ink Free', 'Javanese Text', 'Jokerman', 'Juice ITC', 'Kristen ITC', 'Kunstler Script', 'Leelawadee', 'Leelawadee UI', 'Leelawadee UI Semilight', 'Lucida Bright', 'Lucida Calligraphy', 'Lucida Console', 'Lucida Fax', 'Lucida Handwriting', 'Lucida Sans', 'Lucida Sans Typewriter', 'Lucida Sans Unicode', 'Magneto', 'Maiandra GD', 'Malgun Gothic', 'Malgun Gothic Semilight', 'Matura MT Script Capitals', 'Meiryo', 'Meiryo UI', 'Microsoft Himalaya', 'Microsoft JhengHei', 'Microsoft JhengHei Light', 'Microsoft JhengHei UI', 'Microsoft JhengHei UI Light', 'Microsoft New Tai Lue', 'Microsoft PhagsPa', 'Microsoft Sans Serif', 'Microsoft Tai Le', 'Microsoft Uighur', 'Microsoft YaHei', 'Microsoft YaHei Light', 'Microsoft YaHei UI', 'Microsoft YaHei UI Light', 'Microsoft Yi Baiti', 'MingLiU-ExtB', 'MingLiU_HKSCS-ExtB', 'Mistral', 'Modern No. 20', 'Mongolian Baiti', 'Monospaced', 'Monotype Corsiva', 'MS Gothic', 'MS Mincho', 'MS PGothic', 'MS PMicho', 'MS Reference Sans Serif', 'MS UI Gothic', 'MV Boli', 'Myanmar Text', 'NewCenturySchoolBook', 'Niagara Engraved', 'Niagara Solid', 'Nirmala UI', 'Nirmala UI Semilight', 'NSimSun', 'OCR A Extended', 'Old English Text MT', 'Onyx', 'Palace Script MT', 'Palatino', 'Palatino Linotype', 'Papyrus', 'Parchment', 'Perpetua', 'Perpetua Tittling MT', 'Playbill', 'PMingLiU_ExtB', 'Poor Richard', 'Pristina', 'Rage Italic', 'Ravie', 'Rockwell', 'Rockwell Condensed', 'Rockwell Extra Bold', 'SansSerif', 'Script MT Bold', 'Segoe Print', 'Segoe Script', 'Segoe UI', 'Segoe UI Black', 'Segoe UI Emoji', 'Segoe UI Historic', 'Segoe UI Light', 'Segoe UI Semibold', 'Segoe UI Semilight', 'Segoe UI Symbol', 'Segoe UI Variable', 'Serif', 'Showcard Gothic', 'SimSun', 'SimSun-ExtB', 'Sitka Text', 'Snap ITC', 'Stencil', 'Sylfaen', 'Symbol', 'Tahoma', 'Tempus Sans ITC', 'Times', 'Times New Roman', 'Trebuchet MS', 'Tw Cen MT', 'Tw Cen MT Condensed', 'Tw Cen MT Condensed Extra Bold', 'UD Digi Kyokasho N-B', 'UD Digi Kyokasho N-R', 'UD Digi Kyokasho NK-B', 'UD Digi Kyokasho NK-R', 'UD Digi Kyokasho NP-B', 'UD Digi Kyokasho NP-R', 'Verdana', 'Viner Hand ITC', 'Vivaldi', 'Vladimir Script', 'Wide Latin', 'Yu Gothic', 'Yu Gothic Light', 'Yu Gothic Medium', 'Yu Gothic UI', 'Yu Gothic UI Light', 'Yu Gothic Semibold', 'Yu Gothic Semilight', 'Yu Mincho', 'Yu Mincho Demibold', 'Yu Mincho Light', 'ZapfChancery', 'ZapfDingbats'};
            app.FontNameDropDown.ItemsData = {'Agency FB', 'Algerian', 'Arial', 'Arial Black', 'Arial Narrow', 'Arial Rounded MT Bold', 'AvantGarde', 'Bahnschrift', 'Baskerville Old Face', 'Batang', 'BatangChe', 'Bauhaus 93', 'Bell MT', 'Berlin Sans FB', 'Berlin Sans FB Demi', 'Bernard MT Condensed', 'BIZ UDGothic', 'BIZ UDMicho Medium', 'BIZ UDPGothic', 'BIZ UDPMincho Medium', 'Blackadder ITC', 'Bodoni MT', 'Bodoni MT Black', 'Bodoni MT Condensed', 'Bodoni MT Poster Compressed', 'Book Antiqua', 'Bookman', 'Bookman Old Style', 'Bradley Hand ITC', 'Britannic Bold', 'Broadway', 'Brush Script MT', 'Calibri', 'Calibri Light', 'Californian FB', 'Calisto MT', 'Cambria', 'Cambria Math', 'Candara', 'Candara Light', 'Cascadia Code', 'Cascadia Mono', 'Castellar', 'Centaur', 'Century', 'Century Gothic', 'Century Schoolbook', 'Chiller', 'Colonna MT', 'Comic Sans MS', 'Consolas', 'Constantia', 'Cooper Black', 'Copperplate Gothic Bold', 'Copperplate Gothic Light', 'Corbel', 'Corbel Light', 'Courier', 'Courier New', 'Curlz MT', 'Dialog', 'DialogInput', 'Dotum', 'DotumChe', 'Dubai', 'Dubai Light', 'Dubai Medium', 'Ebrima', 'Edwardian Script ITC', 'Elephant', 'Engravers MT', 'Eras Bold ITC', 'Eras Demi ITC', 'Eras Light ITC', 'Eras Medium ITC', 'Felix Titling', 'Footlight MT Light', 'Forte', 'Franklin Gothic Book', 'Franklin Gothic Demi', 'Franklin Gothic Demi Cond', 'Franklin Gothic Heavy', 'Franklin Gothic Medium', 'Franklin Gothic Medium Cond', 'Freestyle Script', 'French Script MT', 'Gabriola', 'Gadugi', 'Garamond', 'Georgia', 'Gigi', 'Gill Sans MT', 'Gill Sans MT Condensed', 'Gill Sans MT Ext Condensed Bold', 'Gill Sans Ultra Bold', 'Gill Sans Ultra Bold Condensed', 'Gloucester MT Extra Condensed', 'Goudy Old Style', 'Goudy Stout', 'Gulim', 'GulimChe', 'Gungsuh', 'GungsuhChe', 'Haettenschweiler', 'Harlow Solid Italic', 'Harrington', 'Helvetica', 'Helvetica-Narrow', 'High Tower Text', 'Impact', 'Imprint MT Shadow', 'Informal Roman', 'Ink Free', 'Javanese Text', 'Jokerman', 'Juice ITC', 'Kristen ITC', 'Kunstler Script', 'Leelawadee', 'Leelawadee UI', 'Leelawadee UI Semilight', 'Lucida Bright', 'Lucida Calligraphy', 'Lucida Console', 'Lucida Fax', 'Lucida Handwriting', 'Lucida Sans', 'Lucida Sans Typewriter', 'Lucida Sans Unicode', 'Magneto', 'Maiandra GD', 'Malgun Gothic', 'Malgun Gothic Semilight', 'Matura MT Script Capitals', 'Meiryo', 'Meiryo UI', 'Microsoft Himalaya', 'Microsoft JhengHei', 'Microsoft JhengHei Light', 'Microsoft JhengHei UI', 'Microsoft JhengHei UI Light', 'Microsoft New Tai Lue', 'Microsoft PhagsPa', 'Microsoft Sans Serif', 'Microsoft Tai Le', 'Microsoft Uighur', 'Microsoft YaHei', 'Microsoft YaHei Light', 'Microsoft YaHei UI', 'Microsoft YaHei UI Light', 'Microsoft Yi Baiti', 'MingLiU-ExtB', 'MingLiU_HKSCS-ExtB', 'Mistral', 'Modern No. 20', 'Mongolian Baiti', 'Monospaced', 'Monotype Corsiva', 'MS Gothic', 'MS Mincho', 'MS PGothic', 'MS PMicho', 'MS Reference Sans Serif', 'MS UI Gothic', 'MV Boli', 'Myanmar Text', 'NewCenturySchoolBook', 'Niagara Engraved', 'Niagara Solid', 'Nirmala UI', 'Nirmala UI Semilight', 'NSimSun', 'OCR A Extended', 'Old English Text MT', 'Onyx', 'Palace Script MT', 'Palatino', 'Palatino Linotype', 'Papyrus', 'Parchment', 'Perpetua', 'Perpetua Tittling MT', 'Playbill', 'PMingLiU_ExtB', 'Poor Richard', 'Pristina', 'Rage Italic', 'Ravie', 'Rockwell', 'Rockwell Condensed', 'Rockwell Extra Bold', 'SansSerif', 'Script MT Bold', 'Segoe Print', 'Segoe Script', 'Segoe UI', 'Segoe UI Black', 'Segoe UI Emoji', 'Segoe UI Historic', 'Segoe UI Light', 'Segoe UI Semibold', 'Segoe UI Semilight', 'Segoe UI Symbol', 'Segoe UI Variable', 'Serif', 'Showcard Gothic', 'SimSun', 'SimSun-ExtB', 'Sitka Text', 'Snap ITC', 'Stencil', 'Sylfaen', 'Symbol', 'Tahoma', 'Tempus Sans ITC', 'Times', 'Times New Roman', 'Trebuchet MS', 'Tw Cen MT', 'Tw Cen MT Condensed', 'Tw Cen MT Condensed Extra Bold', 'UD Digi Kyokasho N-B', 'UD Digi Kyokasho N-R', 'UD Digi Kyokasho NK-B', 'UD Digi Kyokasho NK-R', 'UD Digi Kyokasho NP-B', 'UD Digi Kyokasho NP-R', 'Verdana', 'Viner Hand ITC', 'Vivaldi', 'Vladimir Script', 'Wide Latin', 'Yu Gothic', 'Yu Gothic Light', 'Yu Gothic Medium', 'Yu Gothic UI', 'Yu Gothic UI Light', 'Yu Gothic Semibold', 'Yu Gothic Semilight', 'Yu Mincho', 'Yu Mincho Demibold', 'Yu Mincho Light', 'ZapfChancery', 'ZapfDingbats '};
            app.FontNameDropDown.ValueChangedFcn = createCallbackFcn(app, @FontNameDropDownValueChanged, true);
            app.FontNameDropDown.Value = 'Times New Roman';
            app.InterpreterDropDownLabel = uilabel(app.FontTab);
            app.InterpreterDropDownLabel.Text = 'Interpreter';
            app.InterpreterDropDown = uidropdown(app.FontTab);
            app.InterpreterDropDown.Items = {'none', 'tex', 'latex'};
            app.InterpreterDropDown.ValueChangedFcn = createCallbackFcn(app, @InterpreterDropDownValueChanged, true);
            app.InterpreterDropDown.Value = 'latex';
            app.TicksTab = uitab(app.TabGroup);
            app.TicksTab.Title = 'Ticks';
            app.StartEditFieldLabel = uilabel(app.TicksTab);
            app.StartEditFieldLabel.Text = 'Start ';
            app.StartEditField = uieditfield(app.TicksTab, 'numeric');
            app.StartEditField.ValueChangedFcn = createCallbackFcn(app, @StartEditFieldValueChanged, true);
            app.XaxisLabel = uilabel(app.TicksTab);
            app.XaxisLabel.Text = 'X axis:';
            app.StepEditFieldLabel = uilabel(app.TicksTab);
            app.StepEditFieldLabel.Text = 'Step';
            app.StepEditField = uieditfield(app.TicksTab, 'numeric');
            app.StepEditField.ValueChangedFcn = createCallbackFcn(app, @StepEditFieldValueChanged, true);
            app.StepEditField.Value = 0.1;
            app.EndEditFieldLabel = uilabel(app.TicksTab);
            app.EndEditFieldLabel.Text = 'End';
            app.EndEditField = uieditfield(app.TicksTab, 'numeric');
            app.EndEditField.ValueChangedFcn = createCallbackFcn(app, @EndEditFieldValueChanged, true);
            app.EndEditField.Value = 1;
            app.YaxisLabel = uilabel(app.TicksTab);
            app.YaxisLabel.Text = 'Y axis:';
            app.StartEditField_2Label = uilabel(app.TicksTab);
            app.StartEditField_2Label.Text = 'Start ';
            app.StartEditField_2 = uieditfield(app.TicksTab, 'numeric');
            app.StartEditField_2.ValueChangedFcn = createCallbackFcn(app, @StartEditField_2ValueChanged, true);
            app.StartEditField_2.Value = -1;
            app.StepEditField_2Label = uilabel(app.TicksTab);
            app.StepEditField_2Label.Text = 'Step';
            app.StepEditField_2 = uieditfield(app.TicksTab, 'numeric');
            app.StepEditField_2.ValueChangedFcn = createCallbackFcn(app, @StepEditField_2ValueChanged, true);
            app.StepEditField_2.Value = 0.5;
            app.EndEditField_2Label = uilabel(app.TicksTab);
            app.EndEditField_2Label.Text = 'End';
            app.EndEditField_2 = uieditfield(app.TicksTab, 'numeric');
            app.EndEditField_2.ValueChangedFcn = createCallbackFcn(app, @EndEditField_2ValueChanged, true);
            app.EndEditField_2.Value = 1;
            app.ScaleDropDownLabel = uilabel(app.TicksTab);
            app.ScaleDropDownLabel.Text = 'Scale';
            app.ScaleDropDown = uidropdown(app.TicksTab);
            app.ScaleDropDown.Items = {'linear', 'log'};
            app.ScaleDropDown.ValueChangedFcn = createCallbackFcn(app, @ScaleDropDownValueChanged, true);
            app.ScaleDropDown.Value = 'linear';
            app.ScaleDropDown_2Label = uilabel(app.TicksTab);
            app.ScaleDropDown_2Label.Text = 'Scale';
            app.ScaleDropDown_2 = uidropdown(app.TicksTab);
            app.ScaleDropDown_2.Items = {'linear', 'log'};
            app.ScaleDropDown_2.ValueChangedFcn = createCallbackFcn(app, @ScaleDropDown_2ValueChanged, true);
            app.ScaleDropDown_2.Value = 'linear';
            app.GridsTab = uitab(app.TabGroup);
            app.GridsTab.Title = 'Grids';
            app.GridCheckBox = uicheckbox(app.GridsTab);
            app.GridCheckBox.ValueChangedFcn = createCallbackFcn(app, @GridCheckBoxValueChanged, true);
            app.GridCheckBox.Text = 'Grid';
            app.GridminorCheckBox = uicheckbox(app.GridsTab);
            app.GridminorCheckBox.ValueChangedFcn = createCallbackFcn(app, @GridminorCheckBoxValueChanged, true);
            app.GridminorCheckBox.Text = 'Grid minor';
            app.LabelsTab = uitab(app.TabGroup);
            app.LabelsTab.Title = 'Labels';
            app.TitleEditFieldLabel = uilabel(app.LabelsTab);
            app.TitleEditFieldLabel.Text = 'Title';
            app.TitleEditField = uieditfield(app.LabelsTab, 'text');
            app.TitleEditField.ValueChangedFcn = createCallbackFcn(app, @TitleEditFieldValueChanged, true);
            app.SubtitleEditFieldLabel = uilabel(app.LabelsTab);
            app.SubtitleEditFieldLabel.Text = 'Subtitle';
            app.SubtitleEditField = uieditfield(app.LabelsTab, 'text');
            app.SubtitleEditField.ValueChangedFcn = createCallbackFcn(app, @SubtitleEditFieldValueChanged, true);
            app.XLabelEditFieldLabel = uilabel(app.LabelsTab);
            app.XLabelEditFieldLabel.Text = 'XLabel';
            app.XLabelEditField = uieditfield(app.LabelsTab, 'text');
            app.XLabelEditField.ValueChangedFcn = createCallbackFcn(app, @XLabelEditFieldValueChanged, true);
            app.XLabelEditField_2Label = uilabel(app.LabelsTab);
            app.XLabelEditField_2Label.Text = 'YLabel';
            app.YLabelEditField = uieditfield(app.LabelsTab, 'text');
            app.YLabelEditField.ValueChangedFcn = createCallbackFcn(app, @YLabelEditFieldValueChanged, true);
            app.LegendTab = uitab(app.TabGroup);
            app.LegendTab.Title = 'Legend';
            app.CreateDestroyLegendCheckBox = uicheckbox(app.LegendTab);
            app.CreateDestroyLegendCheckBox.ValueChangedFcn = createCallbackFcn(app, @CreateDestroyLegendCheckBoxValueChanged, true);
            app.CreateDestroyLegendCheckBox.Text = 'Create/Destroy Legend';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FigureInspector(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end