classdef RunMain < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        NaLyserUIFigure                 matlab.ui.Figure
        Toolbar                         matlab.ui.container.Toolbar
        FigureAnalyser                  matlab.ui.container.toolbar.PushTool
        FilesfoundPanel                 matlab.ui.container.Panel
        Button                          matlab.ui.control.Button
        TabGroup2                       matlab.ui.container.TabGroup
        CharacteristicsTab              matlab.ui.container.Tab
        UITable                         matlab.ui.control.Table
        TransferfunctionTab             matlab.ui.container.Tab
        UITable_2                       matlab.ui.control.Table
        TimeSeriesTab                   matlab.ui.container.Tab
        UITable_3                       matlab.ui.control.Table
        SaveButton                      matlab.ui.control.Button
        TextArea                        matlab.ui.control.TextArea
        ControlPanel                    matlab.ui.container.Panel
        Image                           matlab.ui.control.Image
        AbortButton                     matlab.ui.control.Button
        RunButton                       matlab.ui.control.Button
        FiguresPanel                    matlab.ui.container.Panel
        UniformautoscaleCheckBox        matlab.ui.control.CheckBox
        GridCheckBox                    matlab.ui.control.CheckBox
        LinewidthEditField              matlab.ui.control.NumericEditField
        LinewidthEditFieldLabel         matlab.ui.control.Label
        FontsizeEditField               matlab.ui.control.NumericEditField
        FontsizeEditFieldLabel          matlab.ui.control.Label
        PreviewButton                   matlab.ui.control.Button
        WindowDropDown                  matlab.ui.control.DropDown
        WindowDropDownLabel             matlab.ui.control.Label
        ColorsDropDown                  matlab.ui.control.DropDown
        ColorsDropDownLabel             matlab.ui.control.Label
        PlotcolorsSpinner               matlab.ui.control.Spinner
        SavefiguresCheckBox             matlab.ui.control.CheckBox
        DisplayfiguresCheckBox          matlab.ui.control.CheckBox
        LoadButton                      matlab.ui.control.Button
        SaveQuitButton                  matlab.ui.control.Button
        SetupandAnalysisPanel           matlab.ui.container.Panel
        TabGroup                        matlab.ui.container.TabGroup
        GeneralTab                      matlab.ui.container.Tab
        OpenandReadDataCheckBox         matlab.ui.control.CheckBox
        mergedCheckBox                  matlab.ui.control.CheckBox
        MarkertypesDropDown             matlab.ui.control.DropDown
        MarkertypesDropDownLabel        matlab.ui.control.Label
        LinetypesDropDown               matlab.ui.control.DropDown
        LinetypesDropDownLabel          matlab.ui.control.Label
        FiguresDropDown                 matlab.ui.control.DropDown
        FiguresDropDownLabel            matlab.ui.control.Label
        SubFiguresDropDown              matlab.ui.control.DropDown
        SubFiguresDropDownLabel         matlab.ui.control.Label
        ColorsDropDown_2                matlab.ui.control.DropDown
        ColorsDropDown_2Label           matlab.ui.control.Label
        xDropDownLabel_2                matlab.ui.control.Label
        XaxisDropDown                   matlab.ui.control.DropDown
        XaxisDropDownLabel              matlab.ui.control.Label
        CharacteristicsCheckBox         matlab.ui.control.CheckBox
        TTFBoxTab                       matlab.ui.container.Tab
        MarkertypesDropDown_4           matlab.ui.control.DropDown
        MarkertypesDropDown_4Label      matlab.ui.control.Label
        LinetypesDropDown_4             matlab.ui.control.DropDown
        LinetypesDropDown_4Label        matlab.ui.control.Label
        FiguresDropDown_4               matlab.ui.control.DropDown
        FiguresDropDown_4Label          matlab.ui.control.Label
        SubFiguresDropDown_4            matlab.ui.control.DropDown
        SubFiguresDropDown_4Label       matlab.ui.control.Label
        ColorsDropDown_5                matlab.ui.control.DropDown
        ColorsDropDown_5Label           matlab.ui.control.Label
        XaxisDropDown_4                 matlab.ui.control.DropDown
        XaxisDropDown_4Label            matlab.ui.control.Label
        mergedCheckBox_3                matlab.ui.control.CheckBox
        mergedCheckBox_2                matlab.ui.control.CheckBox
        MarkertypesDropDown_3           matlab.ui.control.DropDown
        MarkertypesDropDown_3Label      matlab.ui.control.Label
        LinetypesDropDown_3             matlab.ui.control.DropDown
        LinetypesDropDown_3Label        matlab.ui.control.Label
        FiguresDropDown_3               matlab.ui.control.DropDown
        FiguresDropDown_3Label          matlab.ui.control.Label
        SubFiguresDropDown_3            matlab.ui.control.DropDown
        SubFiguresDropDown_3Label       matlab.ui.control.Label
        ColorsDropDown_4                matlab.ui.control.DropDown
        ColorsDropDown_4Label           matlab.ui.control.Label
        XaxisDropDown_3                 matlab.ui.control.DropDown
        XaxisDropDown_3Label            matlab.ui.control.Label
        TimeSeriesCheckBox              matlab.ui.control.CheckBox
        TypeDropDown                    matlab.ui.control.DropDown
        TransferfunctionCheckBox        matlab.ui.control.CheckBox
        KeithleyTab                     matlab.ui.container.Tab
        IncludeGateCheckBox             matlab.ui.control.CheckBox
        CycleLengthSpinner              matlab.ui.control.Spinner
        CycleLengthLabel                matlab.ui.control.Label
        GateColumnSpinner               matlab.ui.control.Spinner
        GateColumnSpinnerLabel          matlab.ui.control.Label
        SourceColumnSpinner             matlab.ui.control.Spinner
        SourceColumnSpinnerLabel        matlab.ui.control.Label
        LowDrainColumnSpinner           matlab.ui.control.Spinner
        LowDrainColumnSpinnerLabel      matlab.ui.control.Label
        HighDrainColumnSpinner          matlab.ui.control.Spinner
        HighDrainColumnSpinnerLabel     matlab.ui.control.Label
        GeometryTab                     matlab.ui.container.Tab
        EditField_99                    matlab.ui.control.NumericEditField
        EditField_98                    matlab.ui.control.NumericEditField
        EditField_97                    matlab.ui.control.NumericEditField
        EditField_96                    matlab.ui.control.NumericEditField
        EditField_95                    matlab.ui.control.NumericEditField
        EditField_94                    matlab.ui.control.NumericEditField
        EditField_93                    matlab.ui.control.NumericEditField
        EditField_92                    matlab.ui.control.NumericEditField
        EditField_91                    matlab.ui.control.NumericEditField
        EditField_89                    matlab.ui.control.NumericEditField
        EditField_88                    matlab.ui.control.NumericEditField
        EditField_87                    matlab.ui.control.NumericEditField
        EditField_86                    matlab.ui.control.NumericEditField
        EditField_85                    matlab.ui.control.NumericEditField
        EditField_84                    matlab.ui.control.NumericEditField
        EditField_83                    matlab.ui.control.NumericEditField
        EditField_82                    matlab.ui.control.NumericEditField
        EditField_81                    matlab.ui.control.NumericEditField
        EditField_79                    matlab.ui.control.NumericEditField
        EditField_78                    matlab.ui.control.NumericEditField
        EditField_77                    matlab.ui.control.NumericEditField
        EditField_76                    matlab.ui.control.NumericEditField
        EditField_75                    matlab.ui.control.NumericEditField
        EditField_74                    matlab.ui.control.NumericEditField
        EditField_73                    matlab.ui.control.NumericEditField
        EditField_72                    matlab.ui.control.NumericEditField
        EditField_71                    matlab.ui.control.NumericEditField
        EditField_69                    matlab.ui.control.NumericEditField
        EditField_68                    matlab.ui.control.NumericEditField
        EditField_67                    matlab.ui.control.NumericEditField
        EditField_66                    matlab.ui.control.NumericEditField
        EditField_65                    matlab.ui.control.NumericEditField
        EditField_64                    matlab.ui.control.NumericEditField
        EditField_63                    matlab.ui.control.NumericEditField
        EditField_62                    matlab.ui.control.NumericEditField
        EditField_61                    matlab.ui.control.NumericEditField
        EditField_59                    matlab.ui.control.NumericEditField
        EditField_58                    matlab.ui.control.NumericEditField
        EditField_57                    matlab.ui.control.NumericEditField
        EditField_56                    matlab.ui.control.NumericEditField
        EditField_55                    matlab.ui.control.NumericEditField
        EditField_54                    matlab.ui.control.NumericEditField
        EditField_53                    matlab.ui.control.NumericEditField
        EditField_52                    matlab.ui.control.NumericEditField
        EditField_51                    matlab.ui.control.NumericEditField
        EditField_49                    matlab.ui.control.NumericEditField
        EditField_48                    matlab.ui.control.NumericEditField
        EditField_47                    matlab.ui.control.NumericEditField
        EditField_46                    matlab.ui.control.NumericEditField
        EditField_45                    matlab.ui.control.NumericEditField
        EditField_44                    matlab.ui.control.NumericEditField
        EditField_43                    matlab.ui.control.NumericEditField
        EditField_42                    matlab.ui.control.NumericEditField
        EditField_41                    matlab.ui.control.NumericEditField
        EditField_39                    matlab.ui.control.NumericEditField
        EditField_38                    matlab.ui.control.NumericEditField
        EditField_37                    matlab.ui.control.NumericEditField
        EditField_36                    matlab.ui.control.NumericEditField
        EditField_35                    matlab.ui.control.NumericEditField
        EditField_34                    matlab.ui.control.NumericEditField
        EditField_33                    matlab.ui.control.NumericEditField
        EditField_32                    matlab.ui.control.NumericEditField
        EditField_31                    matlab.ui.control.NumericEditField
        EditField_29                    matlab.ui.control.NumericEditField
        EditField_28                    matlab.ui.control.NumericEditField
        EditField_27                    matlab.ui.control.NumericEditField
        EditField_26                    matlab.ui.control.NumericEditField
        EditField_25                    matlab.ui.control.NumericEditField
        EditField_24                    matlab.ui.control.NumericEditField
        EditField_23                    matlab.ui.control.NumericEditField
        EditField_22                    matlab.ui.control.NumericEditField
        EditField_21                    matlab.ui.control.NumericEditField
        EditField_19                    matlab.ui.control.NumericEditField
        EditField_18                    matlab.ui.control.NumericEditField
        EditField_17                    matlab.ui.control.NumericEditField
        EditField_16                    matlab.ui.control.NumericEditField
        EditField_15                    matlab.ui.control.NumericEditField
        EditField_14                    matlab.ui.control.NumericEditField
        EditField_13                    matlab.ui.control.NumericEditField
        EditField_12                    matlab.ui.control.NumericEditField
        EditField_11                    matlab.ui.control.NumericEditField
        ColumnsSpinner                  matlab.ui.control.Spinner
        ColumnsSpinner_4Label           matlab.ui.control.Label
        RowsSpinner                     matlab.ui.control.Spinner
        RowsSpinner_4Label              matlab.ui.control.Label
        AnalysersTab                    matlab.ui.container.Tab
        RunButton_4                     matlab.ui.control.Button
        CurveIntersectorDropDown_3      matlab.ui.control.DropDown
        CurveIntersectorDropDownLabel_3  matlab.ui.control.Label
        RunButton_3                     matlab.ui.control.Button
        CurveIntersectorDropDown_2      matlab.ui.control.DropDown
        CurveIntersectorDropDownLabel_2  matlab.ui.control.Label
        RunButton_2                     matlab.ui.control.Button
        CurveIntersectorDropDown        matlab.ui.control.DropDown
        CurveIntersectorDropDownLabel   matlab.ui.control.Label
        MachineDropDown                 matlab.ui.control.DropDown
        MachineDropDownLabel            matlab.ui.control.Label
        FIlesPanel                      matlab.ui.container.Panel
        ShowTabCheckBox                 matlab.ui.control.CheckBox
        SearchButton                    matlab.ui.control.Button
        SelectButton_2                  matlab.ui.control.Button
        SelectButton                    matlab.ui.control.Button
        ChipnameEditField               matlab.ui.control.EditField
        ChipnameEditFieldLabel          matlab.ui.control.Label
        OutputfolderpathEditField       matlab.ui.control.EditField
        OutputfolderpathEditFieldLabel  matlab.ui.control.Label
        InputfolderpathEditField        matlab.ui.control.EditField
        InputfolderpathEditFieldLabel   matlab.ui.control.Label
    end

    
    properties (Access = private)
        Property % Description
        Charac_G
        TF_G
        Time_G
    end

    
    methods (Access = private)
        %% Get n different colour vectors
        function colors = getColors(app, n_colors)
            colors = zeros(n_colors, 3);
            x = linspace(0,1,5);
            [R,G,B] = ndgrid(x,x,x);
            rgb = [R(:) G(:) B(:)];
            if (n_colors > floor(size(rgb,1)/3))
                error('You have exceeded the number of available colours.');
            end

            xyz2rgb = [3.2404542 -0.9692660  0.0556434;
                      -1.5371385  1.8760108 -0.2040259;
                      -0.4985314  0.0415560  1.0572252];
            rgb2xyz = inv(xyz2rgb);
            xyz = rgb * rgb2xyz;

            mindist2 = inf(size(rgb,1),1);
            bgrgb = [0.0000 0.4470 0.7410; 
                     0.8500 0.3250 0.0980;
                     0.9290 0.6940 0.1250;
                     0.4940 0.1840 0.5560;
                     0.4660 0.6740 0.1880;
                     0.3010 0.7450 0.9330;
                     0.6350 0.0780 0.1840];
            bgxyz = bgrgb * rgb2xyz;

            for i = 1:size(bgrgb,1)-1
                dX = bsxfun(@minus,xyz,bgxyz(i,:)); % displacement all colors from bg
                dist2 = sum(dX.^2,2);  % square distance
                mindist2 = min(dist2,mindist2);  % dist2 to closest previously-chosen color
            end

            lastColor = [1 1 1];
            for i = 1:n_colors  
                dX = bsxfun(@minus, xyz, lastColor);
                dist2 = sum(dX.^2,2);  % square distance
                mindist2 = min(dist2,mindist2);  % dist2 to closest previously-chosen color
                [~,index] = max(mindist2);  % find the entry farthest from all previously-chosen colors
 
                lastColor = xyz(index,:);
                colors(i,:) = rgb(index,:);  % save for output
            end
            colors(colors > 1) = 1;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            disp('NaLyser v1.10 launching. Thank you for using this service.');
            
            app.InputfolderpathEditField.Value = 'C:\';
            app.OutputfolderpathEditField.Value = 'C:\';
           try
                load("Llanfairpwllgwyngyllgogerychwyrndrobwllllantysiliogogogoch.mat", 'props', 'values', 'visibilities','enable','datae');
                

                for i = 1:length(props)
                    propName = props{i};
                    if(isprop(app, propName))
                        property = app.(propName);
                        if isprop(property, 'Value')
                            app.(propName).Value = values{i};
                        end
                        if isprop(property, 'Visible')
                            app.(props{i}).Visible = visibilities{i};
                        end
                        if (exist('enable','var') && isprop(property, 'Enable'))
                            app.(props{i}).Enable = enable{i};
                        end
                        if (exist('datae','var') && isprop(property, 'Data'))
                            app.(props{i}).Data = datae{i};
                        end
                    end
                end
                
                % For previous releases
                app.mergedCheckBox.Enable = 'off';
                app.mergedCheckBox.Visible = 'off';
                app.mergedCheckBox_2.Enable = 'off';
                app.mergedCheckBox_2.Visible = 'off';
                app.mergedCheckBox_3.Enable = 'off';
                app.mergedCheckBox_3.Visible = 'off';
                app.CycleLengthSpinner.Enable = 'off';
                app.CycleLengthSpinner.Visible = 'off';
                app.CycleLengthLabel.Enable = 'off';
                app.CycleLengthLabel.Visible = 'off';
                app.GateColumnSpinner.Enable = 'off';
                app.GateColumnSpinner.Visible = 'off';
                app.GateColumnSpinnerLabel.Enable = 'off';
                app.GateColumnSpinnerLabel.Visible = 'off';
                app.SourceColumnSpinner.Enable = 'off';
                app.SourceColumnSpinner.Visible = 'off';
                app.SourceColumnSpinnerLabel.Enable = 'off';
                app.SourceColumnSpinnerLabel.Visible = 'off';
                app.LowDrainColumnSpinner.Enable = 'off';
                app.LowDrainColumnSpinner.Visible = 'off';
                app.LowDrainColumnSpinnerLabel.Enable = 'off';
                app.LowDrainColumnSpinnerLabel.Visible = 'off';
                app.HighDrainColumnSpinner.Enable = 'off';
                app.HighDrainColumnSpinner.Visible = 'off';
                app.HighDrainColumnSpinnerLabel.Enable = 'off';
                app.HighDrainColumnSpinnerLabel.Visible = 'off';
           catch
               disp('Some information could not be retrieved. (Or there is no previous save file.)');
           end


            pM = ParametersManager();
            pM.createDictionary();
            app.XaxisDropDown.Items = pM.paramDictionary.keys;
            app.ColorsDropDown_2.Items = pM.paramDictionary.keys;
           
            app.SubFiguresDropDown.Items = pM.paramDictionary.keys;
            app.FiguresDropDown.Items = pM.paramDictionary.keys;
            app.LinetypesDropDown.Items = pM.paramDictionary.keys;
            app.MarkertypesDropDown.Items = pM.paramDictionary.keys;
            
            app.XaxisDropDown_3.Items = pM.paramDictionary.keys;
            app.ColorsDropDown_4.Items = pM.paramDictionary.keys;
           
            app.SubFiguresDropDown_3.Items = pM.paramDictionary.keys;
            app.FiguresDropDown_3.Items = pM.paramDictionary.keys;
            app.LinetypesDropDown_3.Items = pM.paramDictionary.keys;
            app.MarkertypesDropDown_3.Items = pM.paramDictionary.keys;
            
            
            app.XaxisDropDown_4.Items = pM.paramDictionary.keys;
            app.ColorsDropDown_5.Items = pM.paramDictionary.keys;
           
            app.SubFiguresDropDown_4.Items = pM.paramDictionary.keys;
            app.FiguresDropDown_4.Items = pM.paramDictionary.keys;
            app.LinetypesDropDown_4.Items = pM.paramDictionary.keys;
            app.MarkertypesDropDown_4.Items = pM.paramDictionary.keys;
            addpath(genpath("Img"));
            app.Image.ImageSource = 'IWE-1.png';
        end

        % Button pushed function: SaveQuitButton
        function SaveQuitButtonPushed(app, event)
            disp('Saving the GUI properties...')
            props = properties(app);
            values = cell(1, length(props));
            visibilities = cell(1, length(props));
            enable = cell(1, length(props));
            datae = cell(1, length(props));
            
            for i = 1:length(props)
                propName = props{i};
                property = app.(propName);
                if isprop(property, 'Value')
                    values{i} = app.(propName).Value;
                end
                if isprop(property, 'Visible')
                    visibilities{i} = app.(props{i}).Visible;
                end
                if isprop(property, 'Enable')
                    enable{i} = app.(props{i}).Enable;
                end
                if isprop(property, 'Data')
                    datae{i} = app.(props{i}).Data;
                end
            end
            app.FIlesPanel = app.FIlesPanel;
            try
                uisave({'props', 'values', 'visibilities','enable','datae'},join([app.OutputfolderpathEditField.Value "Llanfairpwllgwyngyllgogerychwyrndrobwllllantysiliogogogoch.mat"],""));
                save("Llanfairpwllgwyngyllgogerychwyrndrobwllllantysiliogogogoch.mat", 'props', 'values', 'visibilities','enable','datae');
                disp('Saving successful.');
            catch
                disp('Could not save the data.')
            end

            disp('Closing the GUI...')
            if(isfile('MainData.mat'))
                delete('MainData.mat')
            end
            try 
                us = char(java.lang.System.getProperty('user.name'));
                us = regexp(us,'\.','split');
                us = us{1};
            catch
                us = 'User';
            end
            disp(['Bye ' us '!'])
            delete(app);
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            try
                [file,path] = uigetfile('*.mat', "Load Message");
                if file
                    load([path file], 'props', 'values', 'visibilities','enable','datae');
                end

                for i = 1:length(props)
                    propName = props{i};
                    if(isprop(app, propName))
                        property = app.(propName);
                        if isprop(property, 'Value')
                            app.(propName).Value = values{i};
                        end
                        if isprop(property, 'Visible')
                            app.(props{i}).Visible = visibilities{i};
                        end
                        if (exist('enable','var') && isprop(property, 'Enable'))
                            app.(props{i}).Enable = enable{i};
                        end
                        if (exist('datae','var') && isprop(property, 'Data'))
                            app.(props{i}).Data = datae{i};
                        end
                    end
                end

                % For previous releases
                app.mergedCheckBox.Enable = 'off';
                app.mergedCheckBox.Visible = 'off';
                app.mergedCheckBox_2.Enable = 'off';
                app.mergedCheckBox_2.Visible = 'off';
                app.mergedCheckBox_3.Enable = 'off';
                app.mergedCheckBox_3.Visible = 'off';
                app.CycleLengthSpinner.Enable = 'off';
                app.CycleLengthSpinner.Visible = 'off';
                app.CycleLengthLabel.Enable = 'off';
                app.CycleLengthLabel.Visible = 'off';
                app.GateColumnSpinner.Enable = 'off';
                app.GateColumnSpinner.Visible = 'off';
                app.GateColumnSpinnerLabel.Enable = 'off';
                app.GateColumnSpinnerLabel.Visible = 'off';
                app.SourceColumnSpinner.Enable = 'off';
                app.SourceColumnSpinner.Visible = 'off';
                app.SourceColumnSpinnerLabel.Enable = 'off';
                app.SourceColumnSpinnerLabel.Visible = 'off';
                app.LowDrainColumnSpinner.Enable = 'off';
                app.LowDrainColumnSpinner.Visible = 'off';
                app.LowDrainColumnSpinnerLabel.Enable = 'off';
                app.LowDrainColumnSpinnerLabel.Visible = 'off';
                app.HighDrainColumnSpinner.Enable = 'off';
                app.HighDrainColumnSpinner.Visible = 'off';
                app.HighDrainColumnSpinnerLabel.Enable = 'off';
                app.HighDrainColumnSpinnerLabel.Visible = 'off';
            catch
                disp('An error occured while loading GUI parameters.');
            end
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            Path = app.InputfolderpathEditField.Value;
            outputPath = app.OutputfolderpathEditField.Value;
            
            chipName = app.ChipnameEditField.Value;
            Machine = app.MachineDropDown.Value;
            
            
            %CycleLength = app.CycleLengthSpinner.Value;        
            %HDColumn = app.HighDrainColumnSpinner.Value;
            %LDColumn = app.LowDrainColumnSpinner.Value;
            %SColumn = app.SourceColumnSpinner.Value;
            %GColumn = app.GateColumnSpinner.Value;
            HDColumn = logical(1);
            SColumn = logical(1);
            GColumn = app.IncludeGateCheckBox.Value;
            
            if(app.DisplayfiguresCheckBox.Value)
                displayFigure = 'on';
            else
                displayFigure = 'off';
            end
            if(strcmp(app.ColorsDropDown.Value, 'Distinct'))
                colorPlot = 'distinct';
            elseif(strcmp(app.ColorsDropDown.Value, 'Gradient'))
                colorPlot = 'gradient';
            end
            colorSize = app.PlotcolorsSpinner.Value;
            if(strcmp(app.WindowDropDown.Value, 'Screensize'))
                windowOption = 'screensize';
            elseif(strcmp(app.WindowDropDown.Value, 'Half width'))
                windowOption = 'halfwidth';
            elseif(strcmp(app.WindowDropDown.Value, 'Half height'))
                windowOption = 'halfheight';
            elseif(strcmp(app.WindowDropDown.Value, 'Square'))
                windowOption = 'square';
            elseif(strcmp(app.WindowDropDown.Value, 'Double square'))
                windowOption = 'doublesquare';
            elseif(strcmp(app.WindowDropDown.Value, 'Rectangle'))
                windowOption = 'rectangle';
            elseif(strcmp(app.WindowDropDown.Value, 'ISO A'))
                windowOption = 'ISO A';
            else
                Pix_Dim = str2num(app.WindowDropDown.Value);
                if(length(Pix_Dim) == 2)
                    windowOption = app.WindowDropDown.Value;
                else
                    error('Error. Windows dimensions are invalid.')
                end
            end

            lineWidth = app.LinewidthEditField.Value;
            fontSize = app.FontsizeEditField.Value;
            
            if(app.SavefiguresCheckBox.Value)
                saveOption = 'on';
            else
                saveOption = 'off';
            end

            if(app.GridCheckBox.Value)
                gridOption = true;
            else
                gridOption = false;
            end

            if(app.UniformautoscaleCheckBox.Value) 
                autoscaleOption = true;
            else
                autoscaleOption = false;
            end

            
            Section_OpenNRead = app.OpenandReadDataCheckBox.Value;
            Section_Charac = app.CharacteristicsCheckBox.Value;
                ch_xVar = app.XaxisDropDown.Value;
                ch_colVar = app.ColorsDropDown_2.Value;
                ch_subFigVar = app.SubFiguresDropDown.Value;
                ch_figVar = app.FiguresDropDown.Value;
                ch_lineVar = app.LinetypesDropDown.Value;
                ch_markerVar = app.MarkertypesDropDown.Value;
                if(app.mergedCheckBox.Value)
                    ch_figDisplay = 'merged';
                else
                    ch_figDisplay = 'separate';
                end
                
                ch_pattern = [];

            Section_Trans = app.TransferfunctionCheckBox.Value;
                if(strcmp(app.TypeDropDown.Value, 'GM'))
                    TransVar = 'Conductivity';
                    TransSymbol = app.TypeDropDown.Value;
                elseif(strcmp(app.TypeDropDown.Value, 'V'))
                    TransVar = 'Voltage';
                    TransSymbol = app.TypeDropDown.Value;
                elseif(strcmp(app.TypeDropDown.Value, 'H'))
                    TransVar = 'Transfer function';
                    TransSymbol = app.TypeDropDown.Value;
                end
                tf_xVar = app.XaxisDropDown_4.Value;
                tf_colVar = app.ColorsDropDown_5.Value;
                tf_subFigVar = app.SubFiguresDropDown_4.Value;
                tf_figVar = app.FiguresDropDown_4.Value;
                tf_lineVar = app.LinetypesDropDown_4.Value;
                tf_markerVar = app.MarkertypesDropDown_4.Value;
                if(app.mergedCheckBox_2.Value)
                    tf_figDisplay = 'merged';
                else
                    tf_figDisplay = 'separate';
                end
                
                tf_pattern = [];
                
            Section_TimeSeries = app.TimeSeriesCheckBox.Value;
                time_xVar = app.XaxisDropDown_3.Value;
                time_colVar = app.ColorsDropDown_4.Value;
                time_subFigVar = app.SubFiguresDropDown_3.Value;
                time_figVar = app.FiguresDropDown_3.Value;
                time_lineVar = app.LinetypesDropDown_3.Value;
                time_markerVar = app.MarkertypesDropDown_3.Value;
                if(app.mergedCheckBox_3.Value)
                    time_figDisplay = 'merged';
                else
                    time_figDisplay = 'separate';
                end
                
            valueR = app.RowsSpinner.Value;
            valueC = app.ColumnsSpinner.Value;
            patternG = zeros(valueR,valueC);
            for iR = 1:valueR
               for jC = 1:valueC
                   patternG(iR,jC) = getPattern(iR,jC);
               end
            end

            if(width(app.UITable.Data) == 3)
                ch_DATFileArray = struct('name', app.UITable.Data{app.UITable.Data{:,3},2}, 'folder', app.UITable.Data{app.UITable.Data{:,3},1});
            elseif(width(app.UITable.Data) == 4)
                ch_DATFileArray = struct('sheet', cellstr(app.UITable.Data{app.UITable.Data{:,4},3}), 'name', cellstr(app.UITable.Data{app.UITable.Data{:,4},2}), 'folder', cellstr(app.UITable.Data{app.UITable.Data{:,4},1}));
            end
            if(width(app.UITable_2.Data) == 3)
                tf_DATFileArray = struct('name', app.UITable_2.Data{app.UITable_2.Data{:,3},2}, 'folder', app.UITable_2.Data{app.UITable_2.Data{:,3},1});
            end
            if(width(app.UITable_3.Data) == 3)
                time_DATFileArray = struct('name', app.UITable_3.Data{app.UITable_3.Data{:,3},2}, 'folder', app.UITable_3.Data{app.UITable_3.Data{:,3},1});
            end

            allvars = whos;
            tosave = cellfun(@isempty, regexp({allvars.class}, '^matlab\.(apps)\.(AppBase)|RunMain|matlab\.(ui)\.'));
            save('AppData.mat', allvars(tosave).name);
            save('app2EbUTxRJk7tfV6nRL33oDL1hjD.mat',"saveOption");
            
            %try
               [app.Charac_G, app.TF_G, app.Time_G] = main;
            %catch
            %   disp('An Error occured during the normal operation.')
            %end
            disp('End of execution.')
            
            function value = getPattern(i,j)
                switch i
                    case 1
                        switch j
                            case 1
                                value = app.EditField_11.Value;
                            case 2
                                value = app.EditField_12.Value;
                            case 3
                                value = app.EditField_13.Value;
                            case 4
                                value = app.EditField_14.Value;
                            case 5
                                value = app.EditField_15.Value;
                            case 6
                                value = app.EditField_16.Value;
                            case 7
                                value = app.EditField_17.Value;
                            case 8
                                value = app.EditField_18.Value;
                            case 9
                                value = app.EditField_19.Value;
                            otherwise
                                error("Unrecognised j value");
                        end
                    case 2
                        switch j
                            case 1
                                value = app.EditField_21.Value;
                            case 2
                                value = app.EditField_22.Value;
                            case 3
                                value = app.EditField_23.Value;
                            case 4
                                value = app.EditField_24.Value;
                            case 5
                                value = app.EditField_25.Value;
                            case 6
                                value = app.EditField_26.Value;
                            case 7
                                value = app.EditField_27.Value;
                            case 8
                                value = app.EditField_28.Value;
                            case 9
                                value = app.EditField_29.Value;
                            otherwise
                                error("Unrecognised j value");
                        end
                    case 3
                        switch j
                            case 1
                                value = app.EditField_31.Value;
                            case 2
                                value = app.EditField_32.Value;
                            case 3
                                value = app.EditField_33.Value;
                            case 4
                                value = app.EditField_34.Value;
                            case 5
                                value = app.EditField_35.Value;
                            case 6
                                value = app.EditField_36.Value;
                            case 7
                                value = app.EditField_37.Value;
                            case 8
                                value = app.EditField_38.Value;
                            case 9
                                value = app.EditField_39.Value;
                            otherwise
                                error("Unrecognised j value");
                        end
                    case 4
                        switch j
                            case 1
                                value = app.EditField_41.Value;
                            case 2
                                value = app.EditField_42.Value;
                            case 3
                                value = app.EditField_43.Value;
                            case 4
                                value = app.EditField_44.Value;
                            case 5
                                value = app.EditField_45.Value;
                            case 6
                                value = app.EditField_46.Value;
                            case 7
                                value = app.EditField_47.Value;
                            case 8
                                value = app.EditField_48.Value;
                            case 9
                                value = app.EditField_49.Value;
                            otherwise
                                error("Unrecognised j value");
                        end
                    case 5
                        switch j
                            case 1
                                value = app.EditField_51.Value;
                            case 2
                                value = app.EditField_52.Value;
                            case 3
                                value = app.EditField_53.Value;
                            case 4
                                value = app.EditField_54.Value;
                            case 5
                                value = app.EditField_55.Value;
                            case 6
                                value = app.EditField_56.Value;
                            case 7
                                value = app.EditField_57.Value;
                            case 8
                                value = app.EditField_58.Value;
                            case 9
                                value = app.EditField_59.Value;
                            otherwise
                                error("Unrecognised j value");
                        end
                    case 6
                        switch j
                            case 1
                                value = app.EditField_61.Value;
                            case 2
                                value = app.EditField_62.Value;
                            case 3
                                value = app.EditField_63.Value;
                            case 4
                                value = app.EditField_64.Value;
                            case 5
                                value = app.EditField_65.Value;
                            case 6
                                value = app.EditField_66.Value;
                            case 7
                                value = app.EditField_67.Value;
                            case 8
                                value = app.EditField_68.Value;
                            case 9
                                value = app.EditField_69.Value;
                            otherwise
                                error("Unrecognised j value");
                        end
                    case 7
                        switch j
                            case 1
                                value = app.EditField_71.Value;
                            case 2
                                value = app.EditField_72.Value;
                            case 3
                                value = app.EditField_73.Value;
                            case 4
                                value = app.EditField_74.Value;
                            case 5
                                value = app.EditField_75.Value;
                            case 6
                                value = app.EditField_76.Value;
                            case 7
                                value = app.EditField_77.Value;
                            case 8
                                value = app.EditField_78.Value;
                            case 9
                                value = app.EditField_79.Value;
                            otherwise
                                error("Unrecognised j value");
                        end
                    case 8
                        switch j
                            case 1
                                value = app.EditField_81.Value;
                            case 2
                                value = app.EditField_82.Value;
                            case 3
                                value = app.EditField_83.Value;
                            case 4
                                value = app.EditField_84.Value;
                            case 5
                                value = app.EditField_85.Value;
                            case 6
                                value = app.EditField_86.Value;
                            case 7
                                value = app.EditField_87.Value;
                            case 8
                                value = app.EditField_88.Value;
                            case 9
                                value = app.EditField_89.Value;
                            otherwise
                                error("Unrecognised j value");
                        end
                    case 9
                        switch j
                            case 1
                                value = app.EditField_91.Value;
                            case 2
                                value = app.EditField_92.Value;
                            case 3
                                value = app.EditField_93.Value;
                            case 4
                                value = app.EditField_94.Value;
                            case 5
                                value = app.EditField_95.Value;
                            case 6
                                value = app.EditField_96.Value;
                            case 7
                                value = app.EditField_97.Value;
                            case 8
                                value = app.EditField_98.Value;
                            case 9
                                value = app.EditField_99.Value;
                            otherwise
                                error("Unrecognised j value");
                        end
                    otherwise
                        error("Unrecognised i value");
                end
            
            end
        end

        % Value changed function: CharacteristicsCheckBox
        function CharacteristicsCheckBoxValueChanged(app, event)
            value = app.CharacteristicsCheckBox.Value;
            if value
                app.XaxisDropDown.Enable = 'on';
                app.xDropDownLabel_2.Enable = 'on';
                app.ColorsDropDown_2.Enable = 'on';
                app.SubFiguresDropDown.Enable = 'on';
                app.FiguresDropDown.Enable = 'on';
                app.LinetypesDropDown.Enable = 'on';
                app.MarkertypesDropDown.Enable = 'on';
                %app.mergedCheckBox.Enable = 'on';
                %app.ThresholdvoltageCheckBox.Enable = 'on'; 
                app.XaxisDropDownLabel.Enable = 'on';
                app.ColorsDropDown_2Label.Enable = 'on';
                app.SubFiguresDropDownLabel.Enable = 'on';
                app.FiguresDropDownLabel.Enable = 'on';
                app.LinetypesDropDownLabel.Enable = 'on';
                app.MarkertypesDropDownLabel.Enable = 'on';
            else
                app.XaxisDropDown.Enable = 'off';
                app.xDropDownLabel_2.Enable = 'off';
                app.ColorsDropDown_2.Enable = 'off';
                app.SubFiguresDropDown.Enable = 'off';
                app.FiguresDropDown.Enable = 'off';
                app.LinetypesDropDown.Enable = 'off';
                app.MarkertypesDropDown.Enable = 'off';
                %app.mergedCheckBox.Enable = 'off';
                %pp.ThresholdvoltageCheckBox.Enable = 'off'; 
                app.XaxisDropDownLabel.Enable = 'off';
                app.ColorsDropDown_2Label.Enable = 'off';
                app.SubFiguresDropDownLabel.Enable = 'off';
                app.FiguresDropDownLabel.Enable = 'off';
                app.LinetypesDropDownLabel.Enable = 'off';
                app.MarkertypesDropDownLabel.Enable = 'off';
            end
        end

        % Value changed function: TransferfunctionCheckBox
        function TransferfunctionCheckBoxValueChanged(app, event)
            value = app.TransferfunctionCheckBox.Value;
            if value
                app.TypeDropDown.Enable = 'on';
                app.XaxisDropDown_4.Enable = 'on';
                app.ColorsDropDown_5.Enable = 'on';
                app.SubFiguresDropDown_4.Enable = 'on';
                app.FiguresDropDown_4.Enable = 'on';
                app.LinetypesDropDown_4.Enable = 'on';
                app.MarkertypesDropDown_4.Enable = 'on';
                %app.mergedCheckBox_2.Enable = 'on';
                app.XaxisDropDown_4Label.Enable = 'on';
                app.ColorsDropDown_5Label.Enable = 'on';
                app.SubFiguresDropDown_4Label.Enable = 'on';
                app.FiguresDropDown_4Label.Enable = 'on';
                app.LinetypesDropDown_4Label.Enable = 'on';
                app.MarkertypesDropDown_4Label.Enable = 'on';
            else
                app.TypeDropDown.Enable = 'off';
                app.XaxisDropDown_4.Enable = 'off';
                app.ColorsDropDown_5.Enable = 'off';
                app.SubFiguresDropDown_4.Enable = 'off';
                app.FiguresDropDown_4.Enable = 'off';
                app.LinetypesDropDown_4.Enable = 'off';
                app.MarkertypesDropDown_4.Enable = 'off';
                %app.mergedCheckBox_2.Enable = 'off';
                app.XaxisDropDown_4Label.Enable = 'off'; 
                app.ColorsDropDown_5Label.Enable = 'off'; 
                app.SubFiguresDropDown_4Label.Enable = 'off'; 
                app.FiguresDropDown_4Label.Enable = 'off'; 
                app.LinetypesDropDown_4Label.Enable = 'off'; 
                app.MarkertypesDropDown_4Label.Enable = 'off';
            end
        end

        % Value changed function: TimeSeriesCheckBox
        function TimeSeriesCheckBoxValueChanged(app, event)
            value = app.TimeSeriesCheckBox.Value;
            if value
                app.XaxisDropDown_3.Enable = 'on';
                app.ColorsDropDown_4.Enable = 'on';
                app.SubFiguresDropDown_3.Enable = 'on';
                app.FiguresDropDown_3.Enable = 'on';
                app.LinetypesDropDown_3.Enable = 'on';
                app.MarkertypesDropDown_3.Enable = 'on';
                %app.mergedCheckBox_3.Enable = 'on';
                app.XaxisDropDown_3Label.Enable = 'on';
                app.ColorsDropDown_4Label.Enable = 'on';
                app.SubFiguresDropDown_3Label.Enable = 'on';
                app.FiguresDropDown_3Label.Enable = 'on';
                app.LinetypesDropDown_3Label.Enable = 'on';
                app.MarkertypesDropDown_3Label.Enable = 'on';
            else
                app.XaxisDropDown_3.Enable = 'off';
                app.ColorsDropDown_4.Enable = 'off';
                app.SubFiguresDropDown_3.Enable = 'off';
                app.FiguresDropDown_3.Enable = 'off';
                app.LinetypesDropDown_3.Enable = 'off';
                app.MarkertypesDropDown_3.Enable = 'off';
                %app.mergedCheckBox_3.Enable = 'off';
                app.XaxisDropDown_3Label.Enable = 'off'; 
                app.ColorsDropDown_4Label.Enable = 'off'; 
                app.SubFiguresDropDown_3Label.Enable = 'off'; 
                app.FiguresDropDown_3Label.Enable = 'off'; 
                app.LinetypesDropDown_3Label.Enable = 'off'; 
                app.MarkertypesDropDown_3Label.Enable = 'off';
            end
        end

        % Value changed function: ColorsDropDown
        function ColorsDropDownValueChanged(app, event)
            value = app.ColorsDropDown.Value;
            switch value
                case 'Distinct'
                    app.PlotcolorsSpinner.Enable = 'off';
                case 'Gradient'
                    app.PlotcolorsSpinner.Enable = 'on';
                otherwise
                    error('Error: Unknown variable for plot colors.')
            end
        end

        % Button pushed function: AbortButton
        function AbortButtonPushed(app, event)
            error('Execution interrupted by the user.');
        end

        % Value changed function: MachineDropDown
        function MachineDropDownValueChanged(app, event)
            value = app.MachineDropDown.Value;
            app.TabGroup.SelectedTab = app.GeneralTab;
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            value = app.MachineDropDown.Value;
            switch value
                case 'TTF Box'
                    if(selectedTab == app.KeithleyTab)
                        app.TabGroup.SelectedTab = app.TTFBoxTab;
                    end
                    
                case 'Keithley'
                    if(selectedTab == app.TTFBoxTab)
                        app.TabGroup.SelectedTab = app.KeithleyTab;
                    end
                    
                otherwise
                    
            end
        end

        % Value changed function: XaxisDropDown
        function XaxisDropDownValueChanged(app, event)
            value = app.XaxisDropDown.Value;
            
        end

        % Value changed function: ColorsDropDown_2
        function ColorsDropDown_2ValueChanged(app, event)
            value = app.ColorsDropDown_2.Value;
            
        end

        % Value changed function: SubFiguresDropDown
        function SubFiguresDropDownValueChanged(app, event)
            value = app.SubFiguresDropDown.Value;
            
            
        end

        % Value changed function: FiguresDropDown
        function FiguresDropDownValueChanged(app, event)
            value = app.FiguresDropDown.Value;
            
        end

        % Value changed function: LinetypesDropDown
        function LinetypesDropDownValueChanged(app, event)
            value = app.LinetypesDropDown.Value;
            
        end

        % Value changed function: MarkertypesDropDown
        function MarkertypesDropDownValueChanged(app, event)
            value = app.MarkertypesDropDown.Value;
           
        end

        % Value changed function: RowsSpinner
        function RowsSpinnerValueChanged(app, event)
            valueR = app.RowsSpinner.Value;
            valueC = app.ColumnsSpinner.Value;
            
            for jC = 1:valueC
                Visibility(valueR, jC, 'on');
                if(valueR < 9)
                    Visibility(valueR+1, jC, 'off');
                end
            end
            
            function Visibility(i,j,value)
                switch i
                    case 1
                        switch j
                            case 1
                                app.EditField_11.Visible = value;
                            case 2
                                app.EditField_12.Visible = value;
                            case 3
                                app.EditField_13.Visible = value;
                            case 4
                                app.EditField_14.Visible = value;
                            case 5
                                app.EditField_15.Visible = value;
                            case 6
                                app.EditField_16.Visible = value;
                            case 7
                                app.EditField_17.Visible = value;
                            case 8
                                app.EditField_18.Visible = value;
                            case 9
                                app.EditField_19.Visible = value;
                            otherwise
                                error("Unrecognised j value");
                        end
                    case 2
                        switch j
                            case 1
                                app.EditField_21.Visible = value;
                            case 2
                                app.EditField_22.Visible = value;
                            case 3
                                app.EditField_23.Visible = value;
                            case 4
                                app.EditField_24.Visible = value;
                            case 5
                                app.EditField_25.Visible = value;
                            case 6
                                app.EditField_26.Visible = value;
                            case 7
                                app.EditField_27.Visible = value;
                            case 8
                                app.EditField_28.Visible = value;
                            case 9
                                app.EditField_29.Visible = value;
                            otherwise
                                error("Unrecognised j value");
                        end
                    case 3
                        switch j
                            case 1
                                app.EditField_31.Visible = value;
                            case 2
                                app.EditField_32.Visible = value;
                            case 3
                                app.EditField_33.Visible = value;
                            case 4
                                app.EditField_34.Visible = value;
                            case 5
                                app.EditField_35.Visible = value;
                            case 6
                                app.EditField_36.Visible = value;
                            case 7
                                app.EditField_37.Visible = value;
                            case 8
                                app.EditField_38.Visible = value;
                            case 9
                                app.EditField_39.Visible = value;
                            otherwise
                                error("Unrecognised j value");
                        end
                    case 4
                        switch j
                            case 1
                                app.EditField_41.Visible = value;
                            case 2
                                app.EditField_42.Visible = value;
                            case 3
                                app.EditField_43.Visible = value;
                            case 4
                                app.EditField_44.Visible = value;
                            case 5
                                app.EditField_45.Visible = value;
                            case 6
                                app.EditField_46.Visible = value;
                            case 7
                                app.EditField_47.Visible = value;
                            case 8
                                app.EditField_48.Visible = value;
                            case 9
                                app.EditField_49.Visible = value;
                            otherwise
                                error("Unrecognised j value");
                        end
                    case 5
                        switch j
                            case 1
                                app.EditField_51.Visible = value;
                            case 2
                                app.EditField_52.Visible = value;
                            case 3
                                app.EditField_53.Visible = value;
                            case 4
                                app.EditField_54.Visible = value;
                            case 5
                                app.EditField_55.Visible = value;
                            case 6
                                app.EditField_56.Visible = value;
                            case 7
                                app.EditField_57.Visible = value;
                            case 8
                                app.EditField_58.Visible = value;
                            case 9
                                app.EditField_59.Visible = value;
                            otherwise
                                error("Unrecognised j value");
                        end
                    case 6
                        switch j
                            case 1
                                app.EditField_61.Visible = value;
                            case 2
                                app.EditField_62.Visible = value;
                            case 3
                                app.EditField_63.Visible = value;
                            case 4
                                app.EditField_64.Visible = value;
                            case 5
                                app.EditField_65.Visible = value;
                            case 6
                                app.EditField_66.Visible = value;
                            case 7
                                app.EditField_67.Visible = value;
                            case 8
                                app.EditField_68.Visible = value;
                            case 9
                                app.EditField_69.Visible = value;
                            otherwise
                                error("Unrecognised j value");
                        end
                    case 7
                        switch j
                            case 1
                                app.EditField_71.Visible = value;
                            case 2
                                app.EditField_72.Visible = value;
                            case 3
                                app.EditField_73.Visible = value;
                            case 4
                                app.EditField_74.Visible = value;
                            case 5
                                app.EditField_75.Visible = value;
                            case 6
                                app.EditField_76.Visible = value;
                            case 7
                                app.EditField_77.Visible = value;
                            case 8
                                app.EditField_78.Visible = value;
                            case 9
                                app.EditField_79.Visible = value;
                            otherwise
                                error("Unrecognised j value");
                        end
                    case 8
                        switch j
                            case 1
                                app.EditField_81.Visible = value;
                            case 2
                                app.EditField_82.Visible = value;
                            case 3
                                app.EditField_83.Visible = value;
                            case 4
                                app.EditField_84.Visible = value;
                            case 5
                                app.EditField_85.Visible = value;
                            case 6
                                app.EditField_86.Visible = value;
                            case 7
                                app.EditField_87.Visible = value;
                            case 8
                                app.EditField_88.Visible = value;
                            case 9
                                app.EditField_89.Visible = value;
                            otherwise
                                error("Unrecognised j value");
                        end
                    case 9
                        switch j
                            case 1
                                app.EditField_91.Visible = value;
                            case 2
                                app.EditField_92.Visible = value;
                            case 3
                                app.EditField_93.Visible = value;
                            case 4
                                app.EditField_94.Visible = value;
                            case 5
                                app.EditField_95.Visible = value;
                            case 6
                                app.EditField_96.Visible = value;
                            case 7
                                app.EditField_97.Visible = value;
                            case 8
                                app.EditField_98.Visible = value;
                            case 9
                                app.EditField_99.Visible = value;
                            otherwise
                                error("Unrecognised j value");
                        end
                    otherwise
                        error("Unrecognised i value");
                end
            end
            
        end

        % Value changed function: ColumnsSpinner
        function ColumnsSpinnerValueChanged(app, event)
            valueR = app.RowsSpinner.Value;
            valueC = app.ColumnsSpinner.Value;
            
            for jR = 1:valueR
                Visibility(jR, valueC, 'on');
                if(valueC < 9)
                    Visibility(jR, valueC+1, 'off');
                end
            end
            
            function Visibility(i,j,value)
                switch i
                    case 1
                        switch j
                            case 1
                                app.EditField_11.Visible = value;
                            case 2
                                app.EditField_12.Visible = value;
                            case 3
                                app.EditField_13.Visible = value;
                            case 4
                                app.EditField_14.Visible = value;
                            case 5
                                app.EditField_15.Visible = value;
                            case 6
                                app.EditField_16.Visible = value;
                            case 7
                                app.EditField_17.Visible = value;
                            case 8
                                app.EditField_18.Visible = value;
                            case 9
                                app.EditField_19.Visible = value;
                            otherwise
                                error("Unrecognised j value");
                        end
                    case 2
                        switch j
                            case 1
                                app.EditField_21.Visible = value;
                            case 2
                                app.EditField_22.Visible = value;
                            case 3
                                app.EditField_23.Visible = value;
                            case 4
                                app.EditField_24.Visible = value;
                            case 5
                                app.EditField_25.Visible = value;
                            case 6
                                app.EditField_26.Visible = value;
                            case 7
                                app.EditField_27.Visible = value;
                            case 8
                                app.EditField_28.Visible = value;
                            case 9
                                app.EditField_29.Visible = value;
                            otherwise
                                error("Unrecognised j value");
                        end
                    case 3
                        switch j
                            case 1
                                app.EditField_31.Visible = value;
                            case 2
                                app.EditField_32.Visible = value;
                            case 3
                                app.EditField_33.Visible = value;
                            case 4
                                app.EditField_34.Visible = value;
                            case 5
                                app.EditField_35.Visible = value;
                            case 6
                                app.EditField_36.Visible = value;
                            case 7
                                app.EditField_37.Visible = value;
                            case 8
                                app.EditField_38.Visible = value;
                            case 9
                                app.EditField_39.Visible = value;
                            otherwise
                                error("Unrecognised j value");
                        end
                    case 4
                        switch j
                            case 1
                                app.EditField_41.Visible = value;
                            case 2
                                app.EditField_42.Visible = value;
                            case 3
                                app.EditField_43.Visible = value;
                            case 4
                                app.EditField_44.Visible = value;
                            case 5
                                app.EditField_45.Visible = value;
                            case 6
                                app.EditField_46.Visible = value;
                            case 7
                                app.EditField_47.Visible = value;
                            case 8
                                app.EditField_48.Visible = value;
                            case 9
                                app.EditField_49.Visible = value;
                            otherwise
                                error("Unrecognised j value");
                        end
                    case 5
                        switch j
                            case 1
                                app.EditField_51.Visible = value;
                            case 2
                                app.EditField_52.Visible = value;
                            case 3
                                app.EditField_53.Visible = value;
                            case 4
                                app.EditField_54.Visible = value;
                            case 5
                                app.EditField_55.Visible = value;
                            case 6
                                app.EditField_56.Visible = value;
                            case 7
                                app.EditField_57.Visible = value;
                            case 8
                                app.EditField_58.Visible = value;
                            case 9
                                app.EditField_59.Visible = value;
                            otherwise
                                error("Unrecognised j value");
                        end
                    case 6
                        switch j
                            case 1
                                app.EditField_61.Visible = value;
                            case 2
                                app.EditField_62.Visible = value;
                            case 3
                                app.EditField_63.Visible = value;
                            case 4
                                app.EditField_64.Visible = value;
                            case 5
                                app.EditField_65.Visible = value;
                            case 6
                                app.EditField_66.Visible = value;
                            case 7
                                app.EditField_67.Visible = value;
                            case 8
                                app.EditField_68.Visible = value;
                            case 9
                                app.EditField_69.Visible = value;
                            otherwise
                                error("Unrecognised j value");
                        end
                    case 7
                        switch j
                            case 1
                                app.EditField_71.Visible = value;
                            case 2
                                app.EditField_72.Visible = value;
                            case 3
                                app.EditField_73.Visible = value;
                            case 4
                                app.EditField_74.Visible = value;
                            case 5
                                app.EditField_75.Visible = value;
                            case 6
                                app.EditField_76.Visible = value;
                            case 7
                                app.EditField_77.Visible = value;
                            case 8
                                app.EditField_78.Visible = value;
                            case 9
                                app.EditField_79.Visible = value;
                            otherwise
                                error("Unrecognised j value");
                        end
                    case 8
                        switch j
                            case 1
                                app.EditField_81.Visible = value;
                            case 2
                                app.EditField_82.Visible = value;
                            case 3
                                app.EditField_83.Visible = value;
                            case 4
                                app.EditField_84.Visible = value;
                            case 5
                                app.EditField_85.Visible = value;
                            case 6
                                app.EditField_86.Visible = value;
                            case 7
                                app.EditField_87.Visible = value;
                            case 8
                                app.EditField_88.Visible = value;
                            case 9
                                app.EditField_89.Visible = value;
                            otherwise
                                error("Unrecognised j value");
                        end
                    case 9
                        switch j
                            case 1
                                app.EditField_91.Visible = value;
                            case 2
                                app.EditField_92.Visible = value;
                            case 3
                                app.EditField_93.Visible = value;
                            case 4
                                app.EditField_94.Visible = value;
                            case 5
                                app.EditField_95.Visible = value;
                            case 6
                                app.EditField_96.Visible = value;
                            case 7
                                app.EditField_97.Visible = value;
                            case 8
                                app.EditField_98.Visible = value;
                            case 9
                                app.EditField_99.Visible = value;
                            otherwise
                                error("Unrecognised j value");
                        end
                    otherwise
                        error("Unrecognised i value");
                end
            end
        end

        % Close request function: NaLyserUIFigure
        function NaLyserUIFigureCloseRequest(app, event)
            disp('Closing the GUI...')
            if(isfile('MainData.mat'))
            delete('MainData.mat')
            end
            try 
                us = char(java.lang.System.getProperty('user.name'));
                us = regexp(us,'\.','split');
                us = us{1};
            catch
                us = 'User';
            end
            disp(['Bye ' us '!'])
            delete(app)
        end

        % Button pushed function: SelectButton
        function SelectButtonPushed(app, event)
            prevValue = app.InputfolderpathEditField.Value;
            try
                app.InputfolderpathEditField.Value = uigetdir(app.InputfolderpathEditField.Value , 'Select your input files directory.');
            catch
                app.InputfolderpathEditField.Value = prevValue;
            end
            if(~strcmp(app.InputfolderpathEditField.Value(end), '\') && ~strcmp(app.InputfolderpathEditField.Value(end), '/'))
                app.InputfolderpathEditField.Value = [app.InputfolderpathEditField.Value '\'];
            end
        end

        % Button pushed function: SelectButton_2
        function SelectButton_2Pushed(app, event)
            prevValue = app.OutputfolderpathEditField.Value;
            try
                app.OutputfolderpathEditField.Value = uigetdir(app.OutputfolderpathEditField.Value, 'Select your output files directory.');
            catch
                app.OutputfolderpathEditField.Value = prevValue;
            end
            if(~strcmp(app.OutputfolderpathEditField.Value(end), '\') && ~strcmp(app.OutputfolderpathEditField.Value(end), '/'))
                app.OutputfolderpathEditField.Value = [app.OutputfolderpathEditField.Value '\'];
            end
        end

        % Button pushed function: RunButton_2
        function RunButton_2Pushed(app, event)
            choice = app.CurveIntersectorDropDown.Value;
            %try
                switch choice
                    case 'Characteristics'
                        CurveIntersector(app.Charac_G);
                    case 'Transfer'
                        CurveIntersector(app.TF_G);
                    case 'Time'
                        CurveIntersector(app.Time_G);
                    otherwise
                        error("The Graphs choice was not found.");
                end
            %catch
            %    error("The Analyser could not locate the Graphs object.");
            %end
        end

        % Button pushed function: RunButton_3
        function RunButton_3Pushed(app, event)
            choice = app.CurveIntersectorDropDown_2.Value;
            %try
                switch choice
                    case 'Characteristics'
                        TangentIntersector(app.Charac_G);
                    case 'Transfer'
                        TangentIntersector(app.TF_G);
                    case 'Time'
                        TangentIntersector(app.Time_G);
                    otherwise
                        error("The Graphs choice was not found.");
                end
            %catch
            %    error("The Analyser could not locate the Graphs object.");
            %end
        end

        % Button pushed function: RunButton_4
        function RunButton_4Pushed(app, event)
            choice = app.CurveIntersectorDropDown_3.Value;
            %try
                switch choice
                    case 'Characteristics'
                        FunctionApplyer(app.Charac_G);
                    case 'Transfer'
                        FunctionApplyer(app.TF_G);
                    case 'Time'
                        FunctionApplyer(app.Time_G);
                    otherwise
                        error("The Graphs choice was not found.");
                end
            %catch
            %    error("The Analyser could not locate the Graphs object.");
            %end
        end

        % Value changed function: XaxisDropDown_3
        function XaxisDropDown_3ValueChanged(app, event)
            value = app.XaxisDropDown_3.Value;
            
        end

        % Value changed function: ColorsDropDown_4
        function ColorsDropDown_4ValueChanged(app, event)
            value = app.ColorsDropDown_4.Value;
            
        end

        % Value changed function: SubFiguresDropDown_3
        function SubFiguresDropDown_3ValueChanged(app, event)
            value = app.SubFiguresDropDown_3.Value;
            
        end

        % Value changed function: FiguresDropDown_3
        function FiguresDropDown_3ValueChanged(app, event)
            value = app.FiguresDropDown_3.Value;
            
        end

        % Value changed function: LinetypesDropDown_3
        function LinetypesDropDown_3ValueChanged(app, event)
            value = app.LinetypesDropDown_3.Value;
            
        end

        % Value changed function: MarkertypesDropDown_3
        function MarkertypesDropDown_3ValueChanged(app, event)
            value = app.MarkertypesDropDown_3.Value;
            
        end

        % Value changed function: XaxisDropDown_4
        function XaxisDropDown_4ValueChanged(app, event)
            value = app.XaxisDropDown_4.Value;
            
        end

        % Value changed function: ColorsDropDown_5
        function ColorsDropDown_5ValueChanged(app, event)
            value = app.ColorsDropDown_5.Value;
            
        end

        % Value changed function: SubFiguresDropDown_4
        function SubFiguresDropDown_4ValueChanged(app, event)
            value = app.SubFiguresDropDown_4.Value;
            
        end

        % Value changed function: FiguresDropDown_4
        function FiguresDropDown_4ValueChanged(app, event)
            value = app.FiguresDropDown_4.Value;
            
        end

        % Value changed function: LinetypesDropDown_4
        function LinetypesDropDown_4ValueChanged(app, event)
            value = app.LinetypesDropDown_4.Value;
            
        end

        % Value changed function: MarkertypesDropDown_4
        function MarkertypesDropDown_4ValueChanged(app, event)
            value = app.MarkertypesDropDown_4.Value;
            
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            disp('Saving the GUI properties...')
            props = properties(app);
            values = cell(1, length(props));
            visibilities = cell(1, length(props));
            enable = cell(1, length(props));
            datae = cell(1, length(props));

            for i = 1:length(props)
                propName = props{i};
                property = app.(propName);
                if isprop(property, 'Value')
                    values{i} = app.(propName).Value;
                end
                if isprop(property, 'Visible')
                    visibilities{i} = app.(props{i}).Visible;
                end
                if isprop(property, 'Enable')
                    enable{i} = app.(props{i}).Enable;
                end
                if isprop(property, 'Data')
                    datae{i} = app.(props{i}).Data;
                end
            end
            app.FIlesPanel = app.FIlesPanel;
            try
                uisave({'props', 'values', 'visibilities','enable','datae'},join([app.OutputfolderpathEditField.Value "Llanfairpwllgwyngyllgogerychwyrndrobwllllantysiliogogogoch.mat"],""));
                save("Llanfairpwllgwyngyllgogerychwyrndrobwllllantysiliogogogoch.mat", 'props', 'values', 'visibilities', 'enable','datae');
                disp('Saving successful.');
            catch
                disp('Could not save the data.')
            end
        end

        % Button pushed function: PreviewButton
        function PreviewButtonPushed(app, event)
            set(0,'units','pixels');  
            tmpf = figure('Units','pixels','Visible','on'); % open a figure, save the handles to a variable
            tmpf.WindowState = 'maximized'; % maximize the figure window
            waitfor(tmpf,'InnerPosition');
            Pix_SS = tmpf.InnerPosition;
            delete(tmpf);
            if(strcmp(app.WindowDropDown.Value, 'Screensize'))
                windowDimensions = Pix_SS;
            elseif(strcmp(app.WindowDropDown.Value, 'Half width'))
                windowDimensions = [Pix_SS(1:2) Pix_SS(3)/2 Pix_SS(4)];
            elseif(strcmp(app.WindowDropDown.Value, 'Half height'))
                windowDimensions = [Pix_SS(1:3) Pix_SS(4)/2];
            elseif(strcmp(app.WindowDropDown.Value, 'Square'))
                if(Pix_SS(3) > Pix_SS(4))
                    windowDimensions = [Pix_SS(1:2) Pix_SS(4) Pix_SS(4)];
                else
                    windowDimensions = [Pix_SS(1:2) Pix_SS(3) Pix_SS(3)];
                end
            elseif(strcmp(app.WindowDropDown.Value, 'Double square'))
                if(Pix_SS(3) > Pix_SS(4) * 2)
                    windowDimensions = [Pix_SS(1:2) Pix_SS(4)*2 Pix_SS(4)];
                else
                    windowDimensions = [Pix_SS(1:2) Pix_SS(3) round(Pix_SS(3)/2)];
                end
            elseif(strcmp(app.WindowDropDown.Value, 'Rectangle'))
                GoldenRatio = 1.618033988749;
                if(Pix_SS(3) > Pix_SS(4) * GoldenRatio)
                    windowDimensions = [Pix_SS(1:2) round(Pix_SS(4)*GoldenRatio) Pix_SS(4)];
                else
                    windowDimensions = [Pix_SS(1:2) Pix_SS(3) round(Pix_SS(3)/GoldenRatio)];
                end
            elseif(strcmp(app.WindowDropDown.Value, 'ISO A'))
                if(Pix_SS(3) > Pix_SS(4) * sqrt(2))
                    windowDimensions = [Pix_SS(1:2) round(Pix_SS(4)*sqrt(2)) Pix_SS(4)];
                else
                    windowDimensions = [Pix_SS(1:2) Pix_SS(3) round(Pix_SS(3)/sqrt(2))];
                end
            else
                Pix_Dim = str2num(app.WindowDropDown.Value);
                if(length(Pix_Dim) == 2)
                    windowDimensions = [Pix_SS(1:2) Pix_Dim(1:2)];
                else
                    disp('Error. Windows dimensions are invalid.')
                    windowDimensions = Pix_SS;
                end
            end
            previewF = figure('Name',"Preview",'Position',windowDimensions);
            colors = [0.0000 0.4470 0.7410; % Up to 21 colours
              0.8500 0.3250 0.0980;
              0.9290 0.6940 0.1250;
              0.4940 0.1840 0.5560;
              0.4660 0.6740 0.1880;
              0.3010 0.7450 0.9330;
              0.6350 0.0780 0.1840;
              app.getColors(40)];
    
            if(strcmp(app.ColorsDropDown.Value , 'Gradient'))
                blue = [69,202,255]/255;
                red = [255,27,107]/255;
                colors = [linspace(blue(1),red(1),app.PlotcolorsSpinner.Value)', linspace(blue(2),red(2),app.PlotcolorsSpinner.Value)', linspace(blue(3),red(3),app.PlotcolorsSpinner.Value)'];
            end
            for r = 1:app.RowsSpinner.Value
                for c = 1:app.ColumnsSpinner.Value
                    idx = (r-1) * app.ColumnsSpinner.Value + c;
                    s = subplot(app.RowsSpinner.Value,app.ColumnsSpinner.Value, idx);
                    plot(s,-2*pi:pi/64:2*pi,sin([-2*pi:pi/64:2*pi].*7.5).*cos([-2*pi:pi/64:2*pi].*0.25),'Color',colors(idx,:),'LineWidth',app.LinewidthEditField.Value);
                    xlabel(s, "X axis $x$","FontSize", app.FontsizeEditField.Value,'Interpreter','latex')
                    ylabel(s, "Y axis $y$","FontSize", app.FontsizeEditField.Value,'Interpreter','latex')
                    set(gca, 'fontsize', round(app.FontsizeEditField.Value/sqrt(sqrt(app.RowsSpinner.Value*app.ColumnsSpinner.Value))), 'fontname', 'Times');
                    
                    if(app.GridCheckBox.Value)
                        grid on;
                        grid minor;
                    else
                        grid off;
                    end
                end
            end
        end

        % Value changed function: ShowTabCheckBox
        function ShowTabCheckBoxValueChanged(app, event)
            value = app.ShowTabCheckBox.Value;
            if(value)
                app.FilesfoundPanel.Visible ="on";
            else
                app.FilesfoundPanel.Visible ="off";
            end
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.ShowTabCheckBox.Value = false;
            app.FilesfoundPanel.Visible ="off";
        end

        % Button pushed function: SearchButton
        function SearchButtonPushed(app, event)
            if(app.CharacteristicsCheckBox.Value)
                if(strcmp(app.MachineDropDown.Value, 'TTF Box'))
                    app.UITable.ColumnName = {'Folder'; 'Name'; 'Selection'};
                    fileT = dir([app.InputfolderpathEditField.Value '**/*' app.ChipnameEditField.Value '*' 'Charac' '*' 'dat']);
                    app.UITable.Data = table({fileT(:,1).folder}.', {fileT(:,1).name}.',repelem(true,length(fileT),1));
                    addStyle(app.UITable,uistyle('HorizontalAlignment','right'),'column',1);
                else % Keithley
                    app.UITable.ColumnName = {'Folder'; 'Name'; 'Sheet'; 'Selection'};
                    fileT = dir([app.InputfolderpathEditField.Value '**/*' app.ChipnameEditField.Value '*' 'xls' '*']);
                    sizeFileT = size(fileT);
                    app.UITable.Data = table.empty;
                    for file = 1:sizeFileT
                        sheets = sheetnames([fileT(file).folder, '/', fileT(file).name]);
                        sheets = sheets(contains(sheets,'Run'));
                        app.UITable.Data = [app.UITable.Data; table(repelem(string(fileT(file,1).folder),length(sheets),1), ...
                            repelem(string(fileT(file,1).name),length(sheets),1), sheets,repelem(true,length(sheets),1))];
                    
                    end
                    app.UITable.ColumnEditable = true;
                    addStyle(app.UITable,uistyle('HorizontalAlignment','right'),'column',1);
                end
            else
                app.UITable.Data = table({"In the folder selected,"},{"No files was found."});
            end

            if(strcmp(app.MachineDropDown.Value, 'TTF Box') && app.TransferfunctionCheckBox.Value)
                fileT = dir([app.InputfolderpathEditField.Value '**/*' app.ChipnameEditField.Value '*' app.TypeDropDown.Value '*' 'dat']);
                app.UITable_2.Data = table({fileT(:,1).folder}.', {fileT(:,1).name}.',repelem(true,length(fileT),1));
                addStyle(app.UITable_2,uistyle('HorizontalAlignment','right'),'column',1);
            else
                app.UITable_2.Data = table({"In the folder selected,"},{"No files was found."});
            end

            if(strcmp(app.MachineDropDown.Value, 'TTF Box') && app.TimeSeriesCheckBox.Value)
                fileT = dir([app.InputfolderpathEditField.Value '**/*' app.ChipnameEditField.Value '*' 'TimeSeries' '*' 'dat']);
                app.UITable_3.Data = table({fileT(:,1).folder}.', {fileT(:,1).name}.',repelem(true,length(fileT),1));
                addStyle(app.UITable_3,uistyle('HorizontalAlignment','right'),'column',1);
            else
                app.UITable_3.Data = table({"In the folder selected,"},{"No files was found."});
            end
        end

        % Callback function: FigureAnalyser
        function FigureAnalyserClicked(app, event)
            FigureAnalyser();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.NaLyserUIFigure = uifigure('Visible', 'off');
            app.NaLyserUIFigure.Name = 'NaLyser';
            app.NaLyserUIFigure.Icon = fullfile(pathToMLAPP, 'Img', 'NaLyser.png');
            app.NaLyserUIFigure.CloseRequestFcn = createCallbackFcn(app, @NaLyserUIFigureCloseRequest, true);
            app.Toolbar = uitoolbar(app.NaLyserUIFigure);
            app.FigureAnalyser = uipushtool(app.Toolbar);
            app.FigureAnalyser.Tooltip = {'Figure Analyser'};
            app.FigureAnalyser.ClickedCallback = createCallbackFcn(app, @FigureAnalyserClicked, true);
            app.FigureAnalyser.Icon = fullfile(pathToMLAPP, 'Img', 'FigureModifyer.png');
            app.FIlesPanel = uipanel(app.NaLyserUIFigure);
            app.FIlesPanel.Title = 'Files';
            app.InputfolderpathEditFieldLabel = uilabel(app.FIlesPanel);
            app.InputfolderpathEditFieldLabel.Text = 'Input folder path';
            app.InputfolderpathEditField = uieditfield(app.FIlesPanel, 'text');
            app.InputfolderpathEditField.Value = 'F:\Project\';
            app.OutputfolderpathEditFieldLabel = uilabel(app.FIlesPanel);
            app.OutputfolderpathEditFieldLabel.Text = 'Output folder path';
            app.OutputfolderpathEditField = uieditfield(app.FIlesPanel, 'text');
            app.OutputfolderpathEditField.Value = 'F:\Project\fig\';
            app.ChipnameEditFieldLabel = uilabel(app.FIlesPanel);
            app.ChipnameEditFieldLabel.Text = 'Chip name';
            app.ChipnameEditField = uieditfield(app.FIlesPanel, 'text');
            app.ChipnameEditField.Value = 'chip';
            app.SelectButton = uibutton(app.FIlesPanel, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
            app.SelectButton.Text = 'Select';
            app.SelectButton_2 = uibutton(app.FIlesPanel, 'push');
            app.SelectButton_2.ButtonPushedFcn = createCallbackFcn(app, @SelectButton_2Pushed, true);
            app.SelectButton_2.Text = 'Select';
            app.SearchButton = uibutton(app.FIlesPanel, 'push');
            app.SearchButton.ButtonPushedFcn = createCallbackFcn(app, @SearchButtonPushed, true);
            app.SearchButton.Text = 'Search';
            app.ShowTabCheckBox = uicheckbox(app.FIlesPanel);
            app.ShowTabCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowTabCheckBoxValueChanged, true);
            app.ShowTabCheckBox.Text = 'Show Tab';
            app.SetupandAnalysisPanel = uipanel(app.NaLyserUIFigure);
            app.SetupandAnalysisPanel.Title = 'Setup and Analysis';
            app.MachineDropDownLabel = uilabel(app.SetupandAnalysisPanel);
            app.MachineDropDownLabel.Text = 'Machine';
            app.MachineDropDown = uidropdown(app.SetupandAnalysisPanel);
            app.MachineDropDown.Items = {'TTF Box', 'Keithley', ''};
            app.MachineDropDown.ValueChangedFcn = createCallbackFcn(app, @MachineDropDownValueChanged, true);
            app.MachineDropDown.Value = 'TTF Box';
            app.TabGroup = uitabgroup(app.SetupandAnalysisPanel);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.GeneralTab = uitab(app.TabGroup);
            app.GeneralTab.Title = 'General';
            app.CharacteristicsCheckBox = uicheckbox(app.GeneralTab);
            app.CharacteristicsCheckBox.ValueChangedFcn = createCallbackFcn(app, @CharacteristicsCheckBoxValueChanged, true);
            app.CharacteristicsCheckBox.Text = 'Characteristics';
            app.CharacteristicsCheckBox.Value = true;
            app.XaxisDropDownLabel = uilabel(app.GeneralTab);
            app.XaxisDropDownLabel.Text = 'X axis';
            app.XaxisDropDown = uidropdown(app.GeneralTab);
            app.XaxisDropDown.Items = {'none', 'Gate voltage', 'Drain voltage (single or High)', 'Drain voltage (Low)', 'Frequency', 'Time', 'pH', 'Temperature', 'Run', 'Custom', ''};
            app.XaxisDropDown.Editable = 'on';
            app.XaxisDropDown.ValueChangedFcn = createCallbackFcn(app, @XaxisDropDownValueChanged, true);
            app.XaxisDropDown.Value = 'none';
            app.xDropDownLabel_2 = uilabel(app.GeneralTab);
            app.xDropDownLabel_2.Text = 'Variables/Parameters';
            app.ColorsDropDown_2Label = uilabel(app.GeneralTab);
            app.ColorsDropDown_2Label.Text = 'Colors';
            app.ColorsDropDown_2 = uidropdown(app.GeneralTab);
            app.ColorsDropDown_2.Items = {'none', 'Gate voltage', 'Drain voltage (single or High)', 'Drain voltage (Low)', 'Frequency', 'Time', 'pH', 'Temperature', 'Run', 'Custom', ''};
            app.ColorsDropDown_2.Editable = 'on';
            app.ColorsDropDown_2.ValueChangedFcn = createCallbackFcn(app, @ColorsDropDown_2ValueChanged, true);
            app.ColorsDropDown_2.Value = 'none';
            app.SubFiguresDropDownLabel = uilabel(app.GeneralTab);
            app.SubFiguresDropDownLabel.Text = 'SubFigures';
            app.SubFiguresDropDown = uidropdown(app.GeneralTab);
            app.SubFiguresDropDown.Items = {'none', 'Gate voltage', 'Drain voltage (single or High)', 'Drain voltage (Low)', 'Frequency', 'Time', 'pH', 'Temperature', 'Run', 'Custom', ''};
            app.SubFiguresDropDown.Editable = 'on';
            app.SubFiguresDropDown.ValueChangedFcn = createCallbackFcn(app, @SubFiguresDropDownValueChanged, true);
            app.SubFiguresDropDown.Value = 'none';
            app.FiguresDropDownLabel = uilabel(app.GeneralTab);
            app.FiguresDropDownLabel.Text = 'Figures';
            app.FiguresDropDown = uidropdown(app.GeneralTab);
            app.FiguresDropDown.Items = {'none', 'Gate voltage', 'Drain voltage (single or High)', 'Drain voltage (Low)', 'Frequency', 'Time', 'pH', 'Temperature', 'Run', 'Custom', ''};
            app.FiguresDropDown.Editable = 'on';
            app.FiguresDropDown.ValueChangedFcn = createCallbackFcn(app, @FiguresDropDownValueChanged, true);
            app.FiguresDropDown.Value = 'none';
            app.LinetypesDropDownLabel = uilabel(app.GeneralTab);
            app.LinetypesDropDownLabel.Text = 'Line types';
            app.LinetypesDropDown = uidropdown(app.GeneralTab);
            app.LinetypesDropDown.Items = {'none', 'Gate voltage', 'Drain voltage (single or High)', 'Drain voltage (Low)', 'Frequency', 'Time', 'pH', 'Temperature', 'Run', 'Custom', ''};
            app.LinetypesDropDown.Editable = 'on';
            app.LinetypesDropDown.ValueChangedFcn = createCallbackFcn(app, @LinetypesDropDownValueChanged, true);
            app.LinetypesDropDown.Value = 'none';
            app.MarkertypesDropDownLabel = uilabel(app.GeneralTab);
            app.MarkertypesDropDownLabel.Text = 'Marker types';
            app.MarkertypesDropDown = uidropdown(app.GeneralTab);
            app.MarkertypesDropDown.Items = {'none', 'Gate voltage', 'Drain voltage (single or High)', 'Drain voltage (Low)', 'Frequency', 'Time', 'pH', 'Temperature', 'Run', 'Custom', ''};
            app.MarkertypesDropDown.Editable = 'on';
            app.MarkertypesDropDown.ValueChangedFcn = createCallbackFcn(app, @MarkertypesDropDownValueChanged, true);
            app.MarkertypesDropDown.Value = 'none';
            app.mergedCheckBox = uicheckbox(app.GeneralTab);
            app.mergedCheckBox.Enable = 'off';
            app.mergedCheckBox.Visible = 'off';
            app.mergedCheckBox.Text = 'merged';
            app.OpenandReadDataCheckBox = uicheckbox(app.GeneralTab);
            app.OpenandReadDataCheckBox.Text = 'Open and Read Data';
            app.OpenandReadDataCheckBox.Value = true;
            app.TTFBoxTab = uitab(app.TabGroup);
            app.TTFBoxTab.Title = 'TTF Box';
            app.TransferfunctionCheckBox = uicheckbox(app.TTFBoxTab);
            app.TransferfunctionCheckBox.ValueChangedFcn = createCallbackFcn(app, @TransferfunctionCheckBoxValueChanged, true);
            app.TransferfunctionCheckBox.Text = 'Transfer function';
            app.TypeDropDown = uidropdown(app.TTFBoxTab);
            app.TypeDropDown.Items = {'GM', 'V', 'H'};
            app.TypeDropDown.Value = 'GM';
            app.TimeSeriesCheckBox = uicheckbox(app.TTFBoxTab);
            app.TimeSeriesCheckBox.ValueChangedFcn = createCallbackFcn(app, @TimeSeriesCheckBoxValueChanged, true);
            app.TimeSeriesCheckBox.Text = 'Time Series';
            app.XaxisDropDown_3Label = uilabel(app.TTFBoxTab);
            app.XaxisDropDown_3Label.Enable = 'off';
            app.XaxisDropDown_3Label.Text = 'X axis';
            app.XaxisDropDown_3 = uidropdown(app.TTFBoxTab);
            app.XaxisDropDown_3.Items = {'none', 'Gate voltage', 'Drain voltage (single or High)', 'Drain voltage (Low)', 'Frequency', 'Time', 'pH', 'Temperature', 'Run', 'Custom', ''};
            app.XaxisDropDown_3.Editable = 'on';
            app.XaxisDropDown_3.ValueChangedFcn = createCallbackFcn(app, @XaxisDropDown_3ValueChanged, true);
            app.XaxisDropDown_3.Enable = 'off';
            app.XaxisDropDown_3.Value = 'none';
            app.ColorsDropDown_4Label = uilabel(app.TTFBoxTab);
            app.ColorsDropDown_4Label.Enable = 'off';
            app.ColorsDropDown_4Label.Text = 'Colors';
            app.ColorsDropDown_4 = uidropdown(app.TTFBoxTab);
            app.ColorsDropDown_4.Items = {'none', 'Gate voltage', 'Drain voltage (single or High)', 'Drain voltage (Low)', 'Frequency', 'Time', 'pH', 'Temperature', 'Run', 'Custom', ''};
            app.ColorsDropDown_4.Editable = 'on';
            app.ColorsDropDown_4.ValueChangedFcn = createCallbackFcn(app, @ColorsDropDown_4ValueChanged, true);
            app.ColorsDropDown_4.Enable = 'off';
            app.ColorsDropDown_4.Value = 'none';
            app.SubFiguresDropDown_3Label = uilabel(app.TTFBoxTab);
            app.SubFiguresDropDown_3Label.Enable = 'off';
            app.SubFiguresDropDown_3Label.Text = 'SubFigures';
            app.SubFiguresDropDown_3 = uidropdown(app.TTFBoxTab);
            app.SubFiguresDropDown_3.Items = {'none', 'Gate voltage', 'Drain voltage (single or High)', 'Drain voltage (Low)', 'Frequency', 'Time', 'pH', 'Temperature', 'Run', 'Custom', ''};
            app.SubFiguresDropDown_3.Editable = 'on';
            app.SubFiguresDropDown_3.ValueChangedFcn = createCallbackFcn(app, @SubFiguresDropDown_3ValueChanged, true);
            app.SubFiguresDropDown_3.Enable = 'off';
            app.SubFiguresDropDown_3.Value = 'none';
            app.FiguresDropDown_3Label = uilabel(app.TTFBoxTab);
            app.FiguresDropDown_3Label.Enable = 'off';
            app.FiguresDropDown_3Label.Text = 'Figures';
            app.FiguresDropDown_3 = uidropdown(app.TTFBoxTab);
            app.FiguresDropDown_3.Items = {'none', 'Gate voltage', 'Drain voltage (single or High)', 'Drain voltage (Low)', 'Frequency', 'Time', 'pH', 'Temperature', 'Run', 'Custom', ''};
            app.FiguresDropDown_3.Editable = 'on';
            app.FiguresDropDown_3.ValueChangedFcn = createCallbackFcn(app, @FiguresDropDown_3ValueChanged, true);
            app.FiguresDropDown_3.Enable = 'off';
            app.FiguresDropDown_3.Value = 'none';
            app.LinetypesDropDown_3Label = uilabel(app.TTFBoxTab);
            app.LinetypesDropDown_3Label.Enable = 'off';
            app.LinetypesDropDown_3Label.Text = 'Line types';
            app.LinetypesDropDown_3 = uidropdown(app.TTFBoxTab);
            app.LinetypesDropDown_3.Items = {'none', 'Gate voltage', 'Drain voltage (single or High)', 'Drain voltage (Low)', 'Frequency', 'Time', 'pH', 'Temperature', 'Run', 'Custom', ''};
            app.LinetypesDropDown_3.Editable = 'on';
            app.LinetypesDropDown_3.ValueChangedFcn = createCallbackFcn(app, @LinetypesDropDown_3ValueChanged, true);
            app.LinetypesDropDown_3.Enable = 'off';
            app.LinetypesDropDown_3.Value = 'none';
            app.MarkertypesDropDown_3Label = uilabel(app.TTFBoxTab);
            app.MarkertypesDropDown_3Label.Enable = 'off';
            app.MarkertypesDropDown_3Label.Text = 'Marker types';
            app.MarkertypesDropDown_3 = uidropdown(app.TTFBoxTab);
            app.MarkertypesDropDown_3.Items = {'none', 'Gate voltage', 'Drain voltage (single or High)', 'Drain voltage (Low)', 'Frequency', 'Time', 'pH', 'Temperature', 'Run', 'Custom', ''};
            app.MarkertypesDropDown_3.Editable = 'on';
            app.MarkertypesDropDown_3.ValueChangedFcn = createCallbackFcn(app, @MarkertypesDropDown_3ValueChanged, true);
            app.MarkertypesDropDown_3.Enable = 'off';
            app.MarkertypesDropDown_3.Value = 'none';
            app.mergedCheckBox_2 = uicheckbox(app.TTFBoxTab);
            app.mergedCheckBox_2.Enable = 'off';
            app.mergedCheckBox_2.Visible = 'off';
            app.mergedCheckBox_2.Text = 'merged';
            app.mergedCheckBox_3 = uicheckbox(app.TTFBoxTab);
            app.mergedCheckBox_3.Enable = 'off';
            app.mergedCheckBox_3.Visible = 'off';
            app.mergedCheckBox_3.Text = 'merged';
            app.XaxisDropDown_4Label = uilabel(app.TTFBoxTab);
            app.XaxisDropDown_4Label.Enable = 'off';
            app.XaxisDropDown_4Label.Text = 'X axis';
            app.XaxisDropDown_4 = uidropdown(app.TTFBoxTab);
            app.XaxisDropDown_4.Items = {'none', 'Gate voltage', 'Drain voltage (single or High)', 'Drain voltage (Low)', 'Frequency', 'Time', 'pH', 'Temperature', 'Run', 'Custom', ''};
            app.XaxisDropDown_4.Editable = 'on';
            app.XaxisDropDown_4.ValueChangedFcn = createCallbackFcn(app, @XaxisDropDown_4ValueChanged, true);
            app.XaxisDropDown_4.Enable = 'off';
            app.XaxisDropDown_4.Value = 'none';
            app.ColorsDropDown_5Label = uilabel(app.TTFBoxTab);
            app.ColorsDropDown_5Label.Enable = 'off';
            app.ColorsDropDown_5Label.Text = 'Colors';
            app.ColorsDropDown_5 = uidropdown(app.TTFBoxTab);
            app.ColorsDropDown_5.Items = {'none', 'Gate voltage', 'Drain voltage (single or High)', 'Drain voltage (Low)', 'Frequency', 'Time', 'pH', 'Temperature', 'Run', 'Custom', ''};
            app.ColorsDropDown_5.Editable = 'on';
            app.ColorsDropDown_5.ValueChangedFcn = createCallbackFcn(app, @ColorsDropDown_5ValueChanged, true);
            app.ColorsDropDown_5.Enable = 'off';
            app.ColorsDropDown_5.Value = 'none';
            app.SubFiguresDropDown_4Label = uilabel(app.TTFBoxTab);
            app.SubFiguresDropDown_4Label.Enable = 'off';
            app.SubFiguresDropDown_4Label.Text = 'SubFigures';
            app.SubFiguresDropDown_4 = uidropdown(app.TTFBoxTab);
            app.SubFiguresDropDown_4.Items = {'none', 'Gate voltage', 'Drain voltage (single or High)', 'Drain voltage (Low)', 'Frequency', 'Time', 'pH', 'Temperature', 'Run', 'Custom', ''};
            app.SubFiguresDropDown_4.Editable = 'on';
            app.SubFiguresDropDown_4.ValueChangedFcn = createCallbackFcn(app, @SubFiguresDropDown_4ValueChanged, true);
            app.SubFiguresDropDown_4.Enable = 'off';
            app.SubFiguresDropDown_4.Value = 'none';
            app.FiguresDropDown_4Label = uilabel(app.TTFBoxTab);
            app.FiguresDropDown_4Label.Enable = 'off';
            app.FiguresDropDown_4Label.Text = 'Figures';
            app.FiguresDropDown_4 = uidropdown(app.TTFBoxTab);
            app.FiguresDropDown_4.Items = {'none', 'Gate voltage', 'Drain voltage (single or High)', 'Drain voltage (Low)', 'Frequency', 'Time', 'pH', 'Temperature', 'Run', 'Custom', ''};
            app.FiguresDropDown_4.Editable = 'on';
            app.FiguresDropDown_4.ValueChangedFcn = createCallbackFcn(app, @FiguresDropDown_4ValueChanged, true);
            app.FiguresDropDown_4.Enable = 'off';
            app.FiguresDropDown_4.Value = 'none';
            app.LinetypesDropDown_4Label = uilabel(app.TTFBoxTab);
            app.LinetypesDropDown_4Label.Enable = 'off';
            app.LinetypesDropDown_4Label.Text = 'Line types';
            app.LinetypesDropDown_4 = uidropdown(app.TTFBoxTab);
            app.LinetypesDropDown_4.Items = {'none', 'Gate voltage', 'Drain voltage (single or High)', 'Drain voltage (Low)', 'Frequency', 'Time', 'pH', 'Temperature', 'Run', 'Custom', ''};
            app.LinetypesDropDown_4.Editable = 'on';
            app.LinetypesDropDown_4.ValueChangedFcn = createCallbackFcn(app, @LinetypesDropDown_4ValueChanged, true);
            app.LinetypesDropDown_4.Enable = 'off';
            app.LinetypesDropDown_4.Value = 'none';
            app.MarkertypesDropDown_4Label = uilabel(app.TTFBoxTab);
            app.MarkertypesDropDown_4Label.Enable = 'off';
            app.MarkertypesDropDown_4Label.Text = 'Marker types';
            app.MarkertypesDropDown_4 = uidropdown(app.TTFBoxTab);
            app.MarkertypesDropDown_4.Items = {'none', 'Gate voltage', 'Drain voltage (single or High)', 'Drain voltage (Low)', 'Frequency', 'Time', 'pH', 'Temperature', 'Run', 'Custom', ''};
            app.MarkertypesDropDown_4.Editable = 'on';
            app.MarkertypesDropDown_4.ValueChangedFcn = createCallbackFcn(app, @MarkertypesDropDown_4ValueChanged, true);
            app.MarkertypesDropDown_4.Enable = 'off';
            app.MarkertypesDropDown_4.Value = 'none';
            app.KeithleyTab = uitab(app.TabGroup);
            app.KeithleyTab.Title = 'Keithley';
            app.HighDrainColumnSpinnerLabel = uilabel(app.KeithleyTab);
            app.HighDrainColumnSpinnerLabel.Enable = 'off';
            app.HighDrainColumnSpinnerLabel.Visible = 'off';
            app.HighDrainColumnSpinnerLabel.Text = 'High Drain Column';
            app.HighDrainColumnSpinner = uispinner(app.KeithleyTab);
            app.HighDrainColumnSpinner.Limits = [0 Inf];
            app.HighDrainColumnSpinner.Editable = 'off';
            app.HighDrainColumnSpinner.Enable = 'off';
            app.HighDrainColumnSpinner.Visible = 'off';
            app.HighDrainColumnSpinner.Value = 1;
            app.LowDrainColumnSpinnerLabel = uilabel(app.KeithleyTab);
            app.LowDrainColumnSpinnerLabel.Enable = 'off';
            app.LowDrainColumnSpinnerLabel.Visible = 'off';
            app.LowDrainColumnSpinnerLabel.Text = 'Low Drain Column';
            app.LowDrainColumnSpinner = uispinner(app.KeithleyTab);
            app.LowDrainColumnSpinner.Limits = [0 Inf];
            app.LowDrainColumnSpinner.Editable = 'off';
            app.LowDrainColumnSpinner.Enable = 'off';
            app.LowDrainColumnSpinner.Visible = 'off';
            app.LowDrainColumnSpinner.Value = 2;
            app.SourceColumnSpinnerLabel = uilabel(app.KeithleyTab);
            app.SourceColumnSpinnerLabel.Enable = 'off';
            app.SourceColumnSpinnerLabel.Visible = 'off';
            app.SourceColumnSpinnerLabel.Text = 'Source Column';
            app.SourceColumnSpinner = uispinner(app.KeithleyTab);
            app.SourceColumnSpinner.Limits = [0 Inf];
            app.SourceColumnSpinner.Editable = 'off';
            app.SourceColumnSpinner.Enable = 'off';
            app.SourceColumnSpinner.Visible = 'off';
            app.SourceColumnSpinner.Value = 3;
            app.GateColumnSpinnerLabel = uilabel(app.KeithleyTab);
            app.GateColumnSpinnerLabel.Enable = 'off';
            app.GateColumnSpinnerLabel.Visible = 'off';
            app.GateColumnSpinnerLabel.Text = 'Gate Column';
            app.GateColumnSpinner = uispinner(app.KeithleyTab);
            app.GateColumnSpinner.Limits = [0 Inf];
            app.GateColumnSpinner.Editable = 'off';
            app.GateColumnSpinner.Enable = 'off';
            app.GateColumnSpinner.Visible = 'off';
            app.GateColumnSpinner.Value = 4;
            app.CycleLengthLabel = uilabel(app.KeithleyTab);
            app.CycleLengthLabel.Enable = 'off';
            app.CycleLengthLabel.Visible = 'off';
            app.CycleLengthLabel.Text = 'Cycle Length';
            app.CycleLengthSpinner = uispinner(app.KeithleyTab);
            app.CycleLengthSpinner.Limits = [1 Inf];
            app.CycleLengthSpinner.Enable = 'off';
            app.CycleLengthSpinner.Visible = 'off';
            app.CycleLengthSpinner.Value = 5;
            app.IncludeGateCheckBox = uicheckbox(app.KeithleyTab);
            app.IncludeGateCheckBox.Text = 'Include Gate';
            app.GeometryTab = uitab(app.TabGroup);
            app.GeometryTab.Title = 'Geometry';
            app.RowsSpinner_4Label = uilabel(app.GeometryTab);
            app.RowsSpinner_4Label.Text = 'Rows';
            app.RowsSpinner = uispinner(app.GeometryTab);
            app.RowsSpinner.Limits = [1 9];
            app.RowsSpinner.ValueChangedFcn = createCallbackFcn(app, @RowsSpinnerValueChanged, true);
            app.RowsSpinner.Editable = 'off';
            app.RowsSpinner.Value = 1;
            app.ColumnsSpinner_4Label = uilabel(app.GeometryTab);
            app.ColumnsSpinner_4Label.Text = 'Columns';
            app.ColumnsSpinner = uispinner(app.GeometryTab);
            app.ColumnsSpinner.Limits = [1 9];
            app.ColumnsSpinner.ValueChangedFcn = createCallbackFcn(app, @ColumnsSpinnerValueChanged, true);
            app.ColumnsSpinner.Editable = 'off';
            app.ColumnsSpinner.Value = 1;
            app.EditField_11 = uieditfield(app.GeometryTab, 'numeric');
            app.EditField_12 = uieditfield(app.GeometryTab, 'numeric');
            app.EditField_12.Visible = 'off';
            app.EditField_13 = uieditfield(app.GeometryTab, 'numeric');
            app.EditField_13.Visible = 'off';
            app.EditField_14 = uieditfield(app.GeometryTab, 'numeric');
            app.EditField_14.Visible = 'off';
            app.EditField_15 = uieditfield(app.GeometryTab, 'numeric');
            app.EditField_15.Visible = 'off';
            app.EditField_16 = uieditfield(app.GeometryTab, 'numeric');
            app.EditField_16.Visible = 'off';
            app.EditField_17 = uieditfield(app.GeometryTab, 'numeric');
            app.EditField_17.Visible = 'off';
            app.EditField_18 = uieditfield(app.GeometryTab, 'numeric');
            app.EditField_18.Visible = 'off';
            app.EditField_19 = uieditfield(app.GeometryTab, 'numeric');
            app.EditField_19.Visible = 'off';
            app.EditField_21 = uieditfield(app.GeometryTab, 'numeric');
            app.EditField_21.Visible = 'off';
            app.EditField_22 = uieditfield(app.GeometryTab, 'numeric');
            app.EditField_22.Visible = 'off';
            app.EditField_23 = uieditfield(app.GeometryTab, 'numeric');
            app.EditField_23.Visible = 'off';
            app.EditField_24 = uieditfield(app.GeometryTab, 'numeric');
            app.EditField_24.Visible = 'off';
            app.EditField_25 = uieditfield(app.GeometryTab, 'numeric');
            app.EditField_25.Visible = 'off';
            app.EditField_26 = uieditfield(app.GeometryTab, 'numeric');
            app.EditField_26.Visible = 'off';
            app.EditField_27 = uieditfield(app.GeometryTab, 'numeric');
            app.EditField_27.Visible = 'off';
            app.EditField_28 = uieditfield(app.GeometryTab, 'numeric');
            app.EditField_28.Visible = 'off';
            app.EditField_29 = uieditfield(app.GeometryTab, 'numeric');
            app.EditField_29.Visible = 'off';
            app.EditField_31 = uieditfield(app.GeometryTab, 'numeric');
            app.EditField_31.Visible = 'off';
            app.EditField_32 = uieditfield(app.GeometryTab, 'numeric');
            app.EditField_32.Visible = 'off';
            app.EditField_33 = uieditfield(app.GeometryTab, 'numeric');
            app.EditField_33.Visible = 'off';
            app.EditField_34 = uieditfield(app.GeometryTab, 'numeric');
            app.EditField_34.Visible = 'off';
            app.EditField_35 = uieditfield(app.GeometryTab, 'numeric');
            app.EditField_35.Visible = 'off';
            app.EditField_36 = uieditfield(app.GeometryTab, 'numeric');
            app.EditField_36.Visible = 'off';
            app.EditField_37 = uieditfield(app.GeometryTab, 'numeric');
            app.EditField_37.Visible = 'off';
            app.EditField_38 = uieditfield(app.GeometryTab, 'numeric');
            app.EditField_38.Visible = 'off';
            app.EditField_39 = uieditfield(app.GeometryTab, 'numeric');
            app.EditField_39.Visible = 'off';
            app.EditField_41 = uieditfield(app.GeometryTab, 'numeric');
            app.EditField_41.Visible = 'off';
            app.EditField_42 = uieditfield(app.GeometryTab, 'numeric');
            app.EditField_42.Visible = 'off';
            app.EditField_43 = uieditfield(app.GeometryTab, 'numeric');
            app.EditField_43.Visible = 'off';
            app.EditField_44 = uieditfield(app.GeometryTab, 'numeric');
            app.EditField_44.Visible = 'off';
            app.EditField_45 = uieditfield(app.GeometryTab, 'numeric');
            app.EditField_45.Visible = 'off';
            app.EditField_46 = uieditfield(app.GeometryTab, 'numeric');
            app.EditField_46.Visible = 'off';
            app.EditField_47 = uieditfield(app.GeometryTab, 'numeric');
            app.EditField_47.Visible = 'off';
            app.EditField_48 = uieditfield(app.GeometryTab, 'numeric');
            app.EditField_48.Visible = 'off';
            app.EditField_49 = uieditfield(app.GeometryTab, 'numeric');
            app.EditField_49.Visible = 'off';
            app.EditField_51 = uieditfield(app.GeometryTab, 'numeric');
            app.EditField_51.Visible = 'off';
            app.EditField_52 = uieditfield(app.GeometryTab, 'numeric');
            app.EditField_52.Visible = 'off';
            app.EditField_53 = uieditfield(app.GeometryTab, 'numeric');
            app.EditField_53.Visible = 'off';
            app.EditField_54 = uieditfield(app.GeometryTab, 'numeric');
            app.EditField_54.Visible = 'off';
            app.EditField_55 = uieditfield(app.GeometryTab, 'numeric');
            app.EditField_55.Visible = 'off';
            app.EditField_56 = uieditfield(app.GeometryTab, 'numeric');
            app.EditField_56.Visible = 'off';
            app.EditField_57 = uieditfield(app.GeometryTab, 'numeric');
            app.EditField_57.Visible = 'off';
            app.EditField_58 = uieditfield(app.GeometryTab, 'numeric');
            app.EditField_58.Visible = 'off';
            app.EditField_59 = uieditfield(app.GeometryTab, 'numeric');
            app.EditField_59.Visible = 'off';
            app.EditField_61 = uieditfield(app.GeometryTab, 'numeric');
            app.EditField_61.Visible = 'off';
            app.EditField_62 = uieditfield(app.GeometryTab, 'numeric');
            app.EditField_62.Visible = 'off';
            app.EditField_63 = uieditfield(app.GeometryTab, 'numeric');
            app.EditField_63.Visible = 'off';
            app.EditField_64 = uieditfield(app.GeometryTab, 'numeric');
            app.EditField_64.Visible = 'off';
            app.EditField_65 = uieditfield(app.GeometryTab, 'numeric');
            app.EditField_65.Visible = 'off';
            app.EditField_66 = uieditfield(app.GeometryTab, 'numeric');
            app.EditField_66.Visible = 'off';
            app.EditField_67 = uieditfield(app.GeometryTab, 'numeric');
            app.EditField_67.Visible = 'off';
            app.EditField_68 = uieditfield(app.GeometryTab, 'numeric');
            app.EditField_68.Visible = 'off';
            app.EditField_69 = uieditfield(app.GeometryTab, 'numeric');
            app.EditField_69.Visible = 'off';
            app.EditField_71 = uieditfield(app.GeometryTab, 'numeric');
            app.EditField_71.Visible = 'off';
            app.EditField_72 = uieditfield(app.GeometryTab, 'numeric');
            app.EditField_72.Visible = 'off';
            app.EditField_73 = uieditfield(app.GeometryTab, 'numeric');
            app.EditField_73.Visible = 'off';
            app.EditField_74 = uieditfield(app.GeometryTab, 'numeric');
            app.EditField_74.Visible = 'off';
            app.EditField_75 = uieditfield(app.GeometryTab, 'numeric');
            app.EditField_75.Visible = 'off';
            app.EditField_76 = uieditfield(app.GeometryTab, 'numeric');
            app.EditField_76.Visible = 'off';
            app.EditField_77 = uieditfield(app.GeometryTab, 'numeric');
            app.EditField_77.Visible = 'off';
            app.EditField_78 = uieditfield(app.GeometryTab, 'numeric');
            app.EditField_78.Visible = 'off';
            app.EditField_79 = uieditfield(app.GeometryTab, 'numeric');
            app.EditField_79.Visible = 'off';
            app.EditField_81 = uieditfield(app.GeometryTab, 'numeric');
            app.EditField_81.Visible = 'off';
            app.EditField_82 = uieditfield(app.GeometryTab, 'numeric');
            app.EditField_82.Visible = 'off';
            app.EditField_83 = uieditfield(app.GeometryTab, 'numeric');
            app.EditField_83.Visible = 'off';
            app.EditField_84 = uieditfield(app.GeometryTab, 'numeric');
            app.EditField_84.Visible = 'off';
            app.EditField_85 = uieditfield(app.GeometryTab, 'numeric');
            app.EditField_85.Visible = 'off';
            app.EditField_86 = uieditfield(app.GeometryTab, 'numeric');
            app.EditField_86.Visible = 'off';
            app.EditField_87 = uieditfield(app.GeometryTab, 'numeric');
            app.EditField_87.Visible = 'off';
            app.EditField_88 = uieditfield(app.GeometryTab, 'numeric');
            app.EditField_88.Visible = 'off';
            app.EditField_89 = uieditfield(app.GeometryTab, 'numeric');
            app.EditField_89.Visible = 'off';
            app.EditField_91 = uieditfield(app.GeometryTab, 'numeric');
            app.EditField_91.Visible = 'off';
            app.EditField_92 = uieditfield(app.GeometryTab, 'numeric');
            app.EditField_92.Visible = 'off';
            app.EditField_93 = uieditfield(app.GeometryTab, 'numeric');
            app.EditField_93.Visible = 'off';
            app.EditField_94 = uieditfield(app.GeometryTab, 'numeric');
            app.EditField_94.Visible = 'off';
            app.EditField_95 = uieditfield(app.GeometryTab, 'numeric');
            app.EditField_95.Visible = 'off';
            app.EditField_96 = uieditfield(app.GeometryTab, 'numeric');
            app.EditField_96.Visible = 'off';
            app.EditField_97 = uieditfield(app.GeometryTab, 'numeric');
            app.EditField_97.Visible = 'off';
            app.EditField_98 = uieditfield(app.GeometryTab, 'numeric');
            app.EditField_98.Visible = 'off';
            app.EditField_99 = uieditfield(app.GeometryTab, 'numeric');
            app.EditField_99.Visible = 'off';
            app.AnalysersTab = uitab(app.TabGroup);
            app.AnalysersTab.Title = 'Analysers';
            app.CurveIntersectorDropDownLabel = uilabel(app.AnalysersTab);
            app.CurveIntersectorDropDownLabel.Text = 'Curve Intersector';
            app.CurveIntersectorDropDown = uidropdown(app.AnalysersTab);
            app.CurveIntersectorDropDown.Items = {'Characteristics', 'Transfer', 'Time'};
            app.CurveIntersectorDropDown.Value = 'Characteristics';
            app.RunButton_2 = uibutton(app.AnalysersTab, 'push');
            app.RunButton_2.ButtonPushedFcn = createCallbackFcn(app, @RunButton_2Pushed, true);
            app.RunButton_2.Text = 'Run';
            app.CurveIntersectorDropDownLabel_2 = uilabel(app.AnalysersTab);
            app.CurveIntersectorDropDownLabel_2.Text = '"Tangent" Intersector';
            app.CurveIntersectorDropDown_2 = uidropdown(app.AnalysersTab);
            app.CurveIntersectorDropDown_2.Items = {'Characteristics', 'Transfer', 'Time'};
            app.CurveIntersectorDropDown_2.Value = 'Characteristics';
            app.RunButton_3 = uibutton(app.AnalysersTab, 'push');
            app.RunButton_3.ButtonPushedFcn = createCallbackFcn(app, @RunButton_3Pushed, true);
            app.RunButton_3.Text = 'Run';
            app.CurveIntersectorDropDownLabel_3 = uilabel(app.AnalysersTab);
            app.CurveIntersectorDropDownLabel_3.Text = 'Function Applyer';
            app.CurveIntersectorDropDown_3 = uidropdown(app.AnalysersTab);
            app.CurveIntersectorDropDown_3.Items = {'Characteristics', 'Transfer', 'Time'};
            app.CurveIntersectorDropDown_3.Value = 'Characteristics';
            app.RunButton_4 = uibutton(app.AnalysersTab, 'push');
            app.RunButton_4.ButtonPushedFcn = createCallbackFcn(app, @RunButton_4Pushed, true);
            app.RunButton_4.Text = 'Run';
            app.SaveQuitButton = uibutton(app.NaLyserUIFigure, 'push');
            app.SaveQuitButton.ButtonPushedFcn = createCallbackFcn(app, @SaveQuitButtonPushed, true);
            app.SaveQuitButton.Text = 'Save & Quit';
            app.LoadButton = uibutton(app.NaLyserUIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.FiguresPanel = uipanel(app.NaLyserUIFigure);
            app.FiguresPanel.Title = 'Figures';
            app.DisplayfiguresCheckBox = uicheckbox(app.FiguresPanel);
            app.DisplayfiguresCheckBox.Text = 'Display figures';
            app.DisplayfiguresCheckBox.Value = true;
            app.SavefiguresCheckBox = uicheckbox(app.FiguresPanel);
            app.SavefiguresCheckBox.Text = 'Save figures';
            app.PlotcolorsSpinner = uispinner(app.FiguresPanel);
            app.PlotcolorsSpinner.Limits = [1 Inf];
            app.PlotcolorsSpinner.Enable = 'off';
            app.PlotcolorsSpinner.Value = 8;
            app.ColorsDropDownLabel = uilabel(app.FiguresPanel);
            app.ColorsDropDownLabel.Text = 'Colors';
            app.ColorsDropDown = uidropdown(app.FiguresPanel);
            app.ColorsDropDown.Items = {'Distinct', 'Gradient'};
            app.ColorsDropDown.ValueChangedFcn = createCallbackFcn(app, @ColorsDropDownValueChanged, true);
            app.ColorsDropDown.Value = 'Distinct';
            app.WindowDropDownLabel = uilabel(app.FiguresPanel);
            app.WindowDropDownLabel.Text = 'Window';
            app.WindowDropDown = uidropdown(app.FiguresPanel);
            app.WindowDropDown.Items = {'Screensize', 'Half width', 'Half height', 'Square', 'Double square', 'Rectangle', 'ISO A', 'Custom (XX XX)'};
            app.WindowDropDown.Editable = 'on';
            app.WindowDropDown.Value = 'Screensize';
            app.PreviewButton = uibutton(app.FiguresPanel, 'push');
            app.PreviewButton.ButtonPushedFcn = createCallbackFcn(app, @PreviewButtonPushed, true);
            app.PreviewButton.Text = 'Preview';
            app.FontsizeEditFieldLabel = uilabel(app.FiguresPanel);
            app.FontsizeEditFieldLabel.Text = 'Font size';
            app.FontsizeEditField = uieditfield(app.FiguresPanel, 'numeric');
            app.FontsizeEditField.Limits = [1 Inf];
            app.FontsizeEditField.Value = 24;
            app.LinewidthEditFieldLabel = uilabel(app.FiguresPanel);
            app.LinewidthEditFieldLabel.Text = 'Line width';
            app.LinewidthEditField = uieditfield(app.FiguresPanel, 'numeric');
            app.LinewidthEditField.Limits = [1 Inf];
            app.LinewidthEditField.Value = 2.5;
            app.GridCheckBox = uicheckbox(app.FiguresPanel);
            app.GridCheckBox.Text = 'Grid';
            app.UniformautoscaleCheckBox = uicheckbox(app.FiguresPanel);
            app.UniformautoscaleCheckBox.Text = 'Uniform autoscale';
            app.UniformautoscaleCheckBox.Value = true;
            app.ControlPanel = uipanel(app.NaLyserUIFigure);
            app.ControlPanel.Title = 'Control';
            app.RunButton = uibutton(app.ControlPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run ►';
            app.AbortButton = uibutton(app.ControlPanel, 'push');
            app.AbortButton.ButtonPushedFcn = createCallbackFcn(app, @AbortButtonPushed, true);
            app.AbortButton.Text = 'Abort ■';
            app.Image = uiimage(app.ControlPanel);
            app.TextArea = uitextarea(app.NaLyserUIFigure);
            app.TextArea.Value = {'GUI v1.10 Maltab 2022b'};
            app.SaveButton = uibutton(app.NaLyserUIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.FilesfoundPanel = uipanel(app.NaLyserUIFigure);
            app.FilesfoundPanel.Title = 'Files found';
            app.FilesfoundPanel.Visible = 'off';
            app.TabGroup2 = uitabgroup(app.FilesfoundPanel);
            app.CharacteristicsTab = uitab(app.TabGroup2);
            app.CharacteristicsTab.Title = 'Characteristics';
            app.UITable = uitable(app.CharacteristicsTab);
            app.UITable.ColumnName = {'Folder'; 'Name'; 'Sheet'; 'Selection'};
            app.UITable.ColumnEditable = true;
            app.TransferfunctionTab = uitab(app.TabGroup2);
            app.TransferfunctionTab.Title = 'Transfer function';
            app.UITable_2 = uitable(app.TransferfunctionTab);
            app.UITable_2.ColumnName = {'Folder'; 'Name'; 'Selection'};
            app.UITable_2.ColumnEditable = true;
            app.TimeSeriesTab = uitab(app.TabGroup2);
            app.TimeSeriesTab.Title = 'Time Series';
            app.UITable_3 = uitable(app.TimeSeriesTab);
            app.UITable_3.ColumnName = {'Folder'; 'Name'; 'Selection'};
            app.UITable_3.ColumnEditable = true;
            app.Button = uibutton(app.FilesfoundPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '╳';
            % Show the figure after all components are created
            app.NaLyserUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RunMain

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.NaLyserUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.NaLyserUIFigure)
        end
    end
end